classdef bmes550finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatSumm                    matlab.ui.control.Label
        DiagnosisText               matlab.ui.control.Label
        TotalTrials                 matlab.ui.control.Label
        REMCount                    matlab.ui.control.Label
        ErrorMessage                matlab.ui.control.Label
        TrialList                   matlab.ui.control.Label
        ClearDataButton             matlab.ui.control.Button
        SummaryStatisticsEditField  matlab.ui.control.EditField
        SummaryStatisticsEditFieldLabel  matlab.ui.control.Label
        AddTrialButton              matlab.ui.control.Button
        TrialDropDown               matlab.ui.control.DropDown
        TrialDropDownLabel          matlab.ui.control.Label
        ax                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            set (app.ErrorMessage,'Visible','Off')
            
        end

        % Button pushed function: AddTrialButton
        function AddTrialButtonPushed(app, event)
            % Check if the selected value is already displayed
            if isempty(strfind(app.TrialList.Text,app.TrialDropDown.Value))
                % Remove error message if present
                set (app.ErrorMessage,'Visible','Off')
                % Add to total trial counter
                app.TotalTrials.Text = num2str (str2num(app.TotalTrials.Text) + 1);
                % If not, add trial name to list of displayed names
                app.TrialList.Text = append(app.TrialList.Text,app.TrialDropDown.Value);
                
                % Run function for data
                [rem,data] = REMsleepanalysis (app.TrialDropDown.Value);
                
                % Display if REM detected
                if rem == 1
                    remnewline = append (app.TrialDropDown.Value,': REM sleep detected.');
                    
                    % Add to total count of REM trials
                    app.REMCount.Text = num2str (str2num(app.REMCount.Text) + 1);
                else
                    remnewline = append (app.TrialDropDown.Value,': REM sleep not detected.');
                end
                
                % store current display in a vector (convert if needed)
                if str2num (app.TotalTrials.Text) < 3
                    sumstat_disp = {app.StatSumm.Text};
                else
                    sumstat_disp = app.StatSumm.Text;
                end
                
                % create array for the display
                if str2num (app.TotalTrials.Text) >1
                    sumstat_disp(str2num(app.TotalTrials.Text)) = {remnewline};
                else
                    sumstat_disp = remnewline;
                end
                
                % Store display text in stat summary
                app.StatSumm.Text = sumstat_disp;
                
                % Plot data
                %%%%% need to add a legend to this that incorporates
                %%%%% incoming data
                slp_data = data(:,1);
                time_data = data(:,2) ./ 60;
                hold (app.ax,'on')
                plot (app.ax,time_data,slp_data,'LineWidth',2)
                app.ax.XLim = [0,30];
                app.ax.XTick = 0:5:30;
                app.ax.XTickLabel = {'0','5','10','15','20','25','30'};


            else
                % Display error message
                set (app.ErrorMessage,'Visible','On')
                
            end
            if strcmp (app.TotalTrials.Text,'5')
                if str2num (app.REMCount.Text) >= 2
                    app.DiagnosisText.Text = 'The patient has narcolepsy.';
                else
                    app.DiagnosisText.Text = 'The patient does not have narcolepsy.';
                end
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            set (app.ErrorMessage,'Visible','Off')
            app.TrialList.Text = ' ';
            app.REMCount.Text = '0';
            app.SummaryStatisticsEditField.Value = ' ';
            cla (app.ax,'reset')
            app.DiagnosisText.Text = '';
            
            % Reset graph to default values.
            app.ax.Title.String = 'Sleep Stages';
            app.ax.YLabel.String = 'Stage';
            app.ax.XLabel.String = 'Time (min)';
            app.ax.YLim = [0,5];
            app.ax.YTick = 0:1:5;
            app.ax.YTickLabel = {'W','1','2','3','4','R'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Sleep Stages')
            xlabel(app.ax, 'Time (min)')
            ylabel(app.ax, 'Stage')
            zlabel(app.ax, 'Z')
            app.ax.YLim = [0 5];
            app.ax.YTick = [0 1 2 3 4 5];
            app.ax.YTickLabel = {'W'; '1'; '2'; '3'; '4'; 'R'};
            app.TrialDropDownLabel = uilabel(app.UIFigure);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.UIFigure);
            app.TrialDropDown.Items = {'narco1.txt', 'narco2.txt', 'narco3.txt', 'narco4.txt', 'narco5.txt'};
            app.TrialDropDown.Value = 'narco1.txt';
            app.AddTrialButton = uibutton(app.UIFigure, 'push');
            app.AddTrialButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialButtonPushed, true);
            app.AddTrialButton.Text = 'Add Trial';
            app.SummaryStatisticsEditFieldLabel = uilabel(app.UIFigure);
            app.SummaryStatisticsEditFieldLabel.Text = 'Summary Statistics';
            app.SummaryStatisticsEditField = uieditfield(app.UIFigure, 'text');
            app.SummaryStatisticsEditField.Editable = 'off';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear data';
            app.TrialList = uilabel(app.UIFigure);
            app.TrialList.Enable = 'off';
            app.TrialList.Visible = 'off';
            app.TrialList.Text = '';
            app.ErrorMessage = uilabel(app.UIFigure);
            app.ErrorMessage.Visible = 'off';
            app.ErrorMessage.Text = 'Error: Please select a different trial.';
            app.REMCount = uilabel(app.UIFigure);
            app.REMCount.Enable = 'off';
            app.REMCount.Visible = 'off';
            app.REMCount.Text = '0';
            app.TotalTrials = uilabel(app.UIFigure);
            app.TotalTrials.Enable = 'off';
            app.TotalTrials.Visible = 'off';
            app.TotalTrials.Text = '0';
            app.DiagnosisText = uilabel(app.UIFigure);
            app.DiagnosisText.Text = '';
            app.StatSumm = uilabel(app.UIFigure);
            app.StatSumm.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bmes550finalproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end