classdef InsertPatientData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Table                       matlab.ui.control.Table
        Wel                         matlab.ui.control.Label
        Intro                       matlab.ui.control.Label
        GeneCancerAssociationPanel  matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        Plot                        matlab.ui.control.UIAxes
        PatientInformationPanel     matlab.ui.container.Panel
        PhoneNumber                 matlab.ui.control.EditField
        PhoneNumberEditFieldLabel   matlab.ui.control.Label
        Submit                      matlab.ui.control.Button
        GeneName                    matlab.ui.control.EditField
        GeneNameLabel               matlab.ui.control.Label
        Email                       matlab.ui.control.EditField
        EmailEditFieldLabel         matlab.ui.control.Label
        LastName                    matlab.ui.control.EditField
        LastNameEditFieldLabel      matlab.ui.control.Label
        FirstName                   matlab.ui.control.EditField
        FirstNameEditFieldLabel     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Visible = 'off';
            app.Plot.XTick = [];
            app.Plot.YTick = [];
            app.Plot.Title.String = [];
            app.Intro.Text = ['This GUI is a diagonstic tool that correlates cancer types to an inputed gene variant. ' ...
                'It will return a tree diagram with the gene at the origin and cancer types branching off. Inputed data will be stored' ...
                ' into a new table in the database for future reference.'];
            app.Wel.Text = 'Welcome!';
        end

        % Button pushed function: Submit
        function SubmitButtonPushed(app, event)
            diagram(app);
            submitpatientinfo(app.FirstName.Value, app.LastName.Value, app.Email.Value, app.PhoneNumber.Value, app.GeneName.Value);
            NoData(app);
            
            app.FirstName.Value = '';
            app.LastName.Value = '';
            app.Email.Value = '';
            app.PhoneNumber.Value = '';
            app.GeneName.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientInformationPanel = uipanel(app.UIFigure);
            app.PatientInformationPanel.Title = 'Patient Information';
            app.FirstNameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstName = uieditfield(app.PatientInformationPanel, 'text');
            app.LastNameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastName = uieditfield(app.PatientInformationPanel, 'text');
            app.EmailEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.EmailEditFieldLabel.Text = 'Email';
            app.Email = uieditfield(app.PatientInformationPanel, 'text');
            app.GeneNameLabel = uilabel(app.PatientInformationPanel);
            app.GeneNameLabel.Text = 'Gene Name';
            app.GeneName = uieditfield(app.PatientInformationPanel, 'text');
            app.Submit = uibutton(app.PatientInformationPanel, 'push');
            app.Submit.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.Submit.Text = 'Submit';
            app.PhoneNumberEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.PhoneNumberEditFieldLabel.Text = 'Phone Number';
            app.PhoneNumber = uieditfield(app.PatientInformationPanel, 'text');
            app.GeneCancerAssociationPanel = uipanel(app.UIFigure);
            app.GeneCancerAssociationPanel.Title = 'Gene Cancer Association';
            app.Plot = uiaxes(app.GeneCancerAssociationPanel);
            title(app.Plot, 'Title')
            zlabel(app.Plot, 'Z')
            app.Label = uilabel(app.GeneCancerAssociationPanel);
            app.Intro = uilabel(app.UIFigure);
            app.Intro.Text = 'Label2';
            app.Wel = uilabel(app.UIFigure);
            app.Wel.Text = 'Label3';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Cancer Association'};
            app.Table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InsertPatientData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end