classdef mainguiosteporosispredictor < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                    matlab.ui.Figure
		VitaminDeficiencyDropDown   matlab.ui.control.DropDown
		VitaminDeficiencyLabel      matlab.ui.control.Label
		MaternalHistoryofFracturesDropDown  matlab.ui.control.DropDown
		MaternalHistoryofFracturesLabel  matlab.ui.control.Label
		WeightField                 matlab.ui.control.EditField
		WeightLabel                 matlab.ui.control.Label
		AgeField                    matlab.ui.control.EditField
		StatusLabel                 matlab.ui.control.Label
		WelcomeLabel                matlab.ui.control.Label
		OsteoporosisPredictorLabel  matlab.ui.control.Label
		SmokerDropDown              matlab.ui.control.DropDown
		EthnicityDropDown           matlab.ui.control.DropDown
		NumberofFracturesDropDown   matlab.ui.control.DropDown
		GenderField                 matlab.ui.control.DropDown
		HeightField                 matlab.ui.control.EditField
		GripStrengthField           matlab.ui.control.EditField
		NameField                   matlab.ui.control.EditField
		SmokerLabel                 matlab.ui.control.Label
		EthnicityRaceLabel          matlab.ui.control.Label
		NumberofFracturesLabel      matlab.ui.control.Label
		HeightLabel                 matlab.ui.control.Label
		GripStrengthlbLabel         matlab.ui.control.Label
		GenderLabel                 matlab.ui.control.Label
		AgeLabel                    matlab.ui.control.Label
		NameLabel                   matlab.ui.control.Label
		CalculateButton             matlab.ui.control.Button
		UIAxes                      matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% This function happens right when the GUI is launched.

			% The pie chart is not visible when the GUI is launched.
			app.UIAxes.Visible = "off";
		end

		% Value changed function: NameField
		function NameFieldValueChanged(app, event)
			% This function occurs when the Name Field has changed

			% The value of the name is stored in namevalue
			namevalue = app.NameField.Value;
			% This turns the name from a string to a double
			name = str2double(namevalue);

			% If the name is a number, then the name variable will be cleared
			% and an error message will be displayed saying it is invalid.
			if ~isnan(name)
				clear namevalue
				app.StatusLabel.Text = 'Invalid Name Input';
			else
			% Otherwise, the error message will not appear.
				app.StatusLabel.Text = '';
			end
			
		end

		% Value changing function: NameField
		function NameFieldValueChanging(app, event)
			% This function happens if the name value is changing.
			% Both the pie chart and the welcome message will disappear.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
			
		end

		% Value changed function: AgeField
		function AgeFieldValueChanged(app, event)
    % This function occurs when the Age Field has changed

		% The value of the age is stored in agevalue
		 agevalue = app.AgeField.Value;
		% This turns the age from a string to a double
		 age = str2double(agevalue);
		% If the age is not a number, then the age variable will be cleared
		% and an error message will be displayed saying it is invalid.
		 if isnan(age)
			 clear agevalue
			 app.StatusLabel.Text = 'Invalid Age Input';
		 else
		% Otherwise, the error message will not appear.
			 app.StatusLabel.Text = '';
		 end

		end

		% Value changing function: AgeField
		function AgeFieldValueChanging(app, event)
			% This function happens if the age value is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user starts typing.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Value changed function: GripStrengthField
		function GripStrengthFieldValueChanged(app, event)
    % This function occurs when the Grip Strenth Field has changed		
		% The value of the grip strenth is stored in gripvalue
		  gripvalue = app.GripStrengthField.Value;
		% This turns the grip from a string to a double
			grip = str2double(gripvalue);
		% If the grip is not a number, then the grip variable will be cleared
		% and an error message will be displayed saying it is invalid.
			if isnan(grip)
				clear gripvalue;
				app.StatusLabel.Text = 'Invalid Grip Strength Input';
			else
		% Otherwise, the error message will not appear.
				app.StatusLabel.Text = '';
			end

		end

		% Value changing function: GripStrengthField
		function GripStrengthFieldValueChanging(app, event)
			% This function happens if the grip strength value is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user starts typing.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Value changed function: HeightField
		function HeightFieldValueChanged(app, event)
		% This function occurs when the Height Field has changed		
		% The value of the height is stored in heightvalue
			heightvalue = app.HeightField.Value;
		% This turns the height from a string to a double
			height = str2double(heightvalue);
		% If the height is not a number, then the height variable will be cleared
		% and an error message will be displayed saying it is invalid.
			if isnan(height)
				clear heightvalue
				app.StatusLabel.Text = 'Invalid Height Input';
		% Otherwise, the error message will not appear.
			else
				app.StatusLabel.Text = '';
			end
		end

		% Value changing function: HeightField
		function HeightFieldValueChanging(app, event)
			% This function happens if the height value is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user starts typing.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Value changed function: WeightField
		function WeightFieldValueChanged(app, event)
		% This function occurs when the Weight Field has changed		
		% The value of the weight is stored in weightvalue
			weightvalue = app.WeightField.Value;
		% This turns the weight from a string to a double
			weight = str2double(weightvalue);
		% If the weight is not a number, then the weight variable will be cleared
		% and an error message will be displayed saying it is invalid.
			if isnan(weight)
				clear weightvalue
				app.StatusLabel.Text = 'Invalid Weight Input';
		% Otherwise, the error message will not appear.
			else
				app.StatusLabel.Text = '';
			end
		end

		% Value changing function: WeightField
		function WeightFieldValueChanging(app, event)
			% This function happens if the weight value is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user starts typing.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: GenderField
		function GenderFieldDropDownOpening(app, event)
			% This function happens if the gender field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: NumberofFracturesDropDown
		function NumberofFracturesDropDownOpening(app, event)
			% This function happens if the number of fractures field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: EthnicityDropDown
		function EthnicityDropDownOpening(app, event)
			% This function happens if the ethnicity field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: VitaminDeficiencyDropDown
		function VitaminDeficiencyDropDownOpening(app, event)
			% This function happens if the vitamin deficiency field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: MaternalHistoryofFracturesDropDown
		function MaternalHistoryofFracturesDropDownOpening(app, event)
			% This function happens if the maternal history of fractures field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Drop down opening function: SmokerDropDown
		function SmokerDropDownOpening(app, event)
			% This function happens if the smoker field is changing.
			
			% Both the pie chart and the welcome message will disappear once the 
			% user opens the dropdown.
			app.WelcomeLabel.Visible = "off";
			app.UIAxes.Visible = "off";
		end

		% Button pushed function: CalculateButton
		function CalculateButtonPushed(app, event)
			% This function when run when the Calculate button is pushed.

			% If the name, age, grip strength, height, or weight field is empty,
			% this if statement will run.
			if isempty(app.NameField.Value) || isempty(app.AgeField.Value) || isempty(app.GripStrengthField.Value) || isempty(app.HeightField.Value) || isempty(app.WeightField.Value)
				% The status label will give an error saying there is a missing
				% field and the pie chart will not appear
				app.StatusLabel.Text = 'Error: Missing Field';
				app.UIAxes.Visible = "off";
			else
				% Otherwise, the pie chart will appear and there will be no error.
				app.StatusLabel.Text = '';
				app.UIAxes.Visible = "on";
			end

			% This extracts all the values of the variables and stores them in
			% their respective variables.
			namevalue = app.NameField.Value;
			agevalue = app.AgeField.Value;
			gendervalue = app.GenderField.Value;
			gripvalue = app.GripStrengthField.Value;
			heightvalue = app.HeightField.Value;
			weightvalue = app.WeightField.Value;
			fracturesvalue = app.NumberofFracturesDropDown.Value;
			ethnicityvalue = app.EthnicityDropDown.Value;
			vitaminvalue = app.VitaminDeficiencyDropDown.Value;
			mhfvalue = app.MaternalHistoryofFracturesDropDown.Value;
			smokervalue = app.SmokerDropDown.Value;

			% This runs the calculaterisk function based on the values of
			% provided by the user in this GUI.
			riskpercentages = calculaterisk(struct('name',namevalue, 'age',agevalue, 'gender',gendervalue, 'grip', gripvalue , 'height' , heightvalue, 'weight', weightvalue, 'fractures' , fracturesvalue , 'ethnicity', ethnicityvalue, 'vitamin', vitaminvalue, 'mhf', mhfvalue, 'smoker', smokervalue ));
			

			% This creates the pie chart based on the percentages calculated by
			% the calculaterisk function and labels each section of the pie
			% chart.
			p = pie(app.UIAxes, riskpercentages);
			pvalues = findobj(p,'Type','text');
			percentValues = get(pvalues,'String'); 
			txt = {'Yes: ';'No: ';'Unknown: '}; 
			append = strcat(txt,percentValues); 
			pvalues(1).String = append(1);
			pvalues(2).String = append(2);
			pvalues(3).String = append(3);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Osteoporosis Prediction')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.CalculateButton = uibutton(app.UIFigure, 'push');
			app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.CalculateButton.Text = 'Calculate';
			app.NameLabel = uilabel(app.UIFigure);
			app.NameLabel.Text = 'Name:';
			app.AgeLabel = uilabel(app.UIFigure);
			app.AgeLabel.Text = 'Age:';
			app.GenderLabel = uilabel(app.UIFigure);
			app.GenderLabel.Text = 'Gender:';
			app.GripStrengthlbLabel = uilabel(app.UIFigure);
			app.GripStrengthlbLabel.Text = 'Grip Strength (lb):';
			app.HeightLabel = uilabel(app.UIFigure);
			app.HeightLabel.Text = 'Height (in):';
			app.NumberofFracturesLabel = uilabel(app.UIFigure);
			app.NumberofFracturesLabel.Text = 'Number of Fractures?';
			app.EthnicityRaceLabel = uilabel(app.UIFigure);
			app.EthnicityRaceLabel.Text = 'Ethnicity/Race:';
			app.SmokerLabel = uilabel(app.UIFigure);
			app.SmokerLabel.Text = 'Smoker?';
			app.NameField = uieditfield(app.UIFigure, 'text');
			app.NameField.ValueChangedFcn = createCallbackFcn(app, @NameFieldValueChanged, true);
			app.NameField.ValueChangingFcn = createCallbackFcn(app, @NameFieldValueChanging, true);
			app.GripStrengthField = uieditfield(app.UIFigure, 'text');
			app.GripStrengthField.ValueChangedFcn = createCallbackFcn(app, @GripStrengthFieldValueChanged, true);
			app.GripStrengthField.ValueChangingFcn = createCallbackFcn(app, @GripStrengthFieldValueChanging, true);
			app.HeightField = uieditfield(app.UIFigure, 'text');
			app.HeightField.ValueChangedFcn = createCallbackFcn(app, @HeightFieldValueChanged, true);
			app.HeightField.ValueChangingFcn = createCallbackFcn(app, @HeightFieldValueChanging, true);
			app.GenderField = uidropdown(app.UIFigure);
			app.GenderField.Items = {'Male', 'Female'};
			app.GenderField.DropDownOpeningFcn = createCallbackFcn(app, @GenderFieldDropDownOpening, true);
			app.GenderField.Value = 'Male';
			app.NumberofFracturesDropDown = uidropdown(app.UIFigure);
			app.NumberofFracturesDropDown.Items = {'0', '1', '2', '2+'};
			app.NumberofFracturesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NumberofFracturesDropDownOpening, true);
			app.NumberofFracturesDropDown.Value = '0';
			app.EthnicityDropDown = uidropdown(app.UIFigure);
			app.EthnicityDropDown.Items = {'Non hispanic White', 'Asian', 'Hispanic', 'African American'};
			app.EthnicityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EthnicityDropDownOpening, true);
			app.EthnicityDropDown.Value = 'Non hispanic White';
			app.SmokerDropDown = uidropdown(app.UIFigure);
			app.SmokerDropDown.Items = {'No', 'Yes'};
			app.SmokerDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SmokerDropDownOpening, true);
			app.SmokerDropDown.Value = 'No';
			app.OsteoporosisPredictorLabel = uilabel(app.UIFigure);
			app.OsteoporosisPredictorLabel.Text = 'Osteoporosis Predictor';
			app.WelcomeLabel = uilabel(app.UIFigure);
			app.WelcomeLabel.Text = {'                                   Welcome!                                                                                    This website will calculate a "range" value based on the information provided. This will then return a calculation predicting your risk of osteoporosis based on'; 'previous diagnoses by other physicians on other patients with similar medical factors as you.'};
			app.StatusLabel = uilabel(app.UIFigure);
			app.StatusLabel.Text = '';
			app.AgeField = uieditfield(app.UIFigure, 'text');
			app.AgeField.ValueChangedFcn = createCallbackFcn(app, @AgeFieldValueChanged, true);
			app.AgeField.ValueChangingFcn = createCallbackFcn(app, @AgeFieldValueChanging, true);
			app.WeightLabel = uilabel(app.UIFigure);
			app.WeightLabel.Text = 'Weight (lb):';
			app.WeightField = uieditfield(app.UIFigure, 'text');
			app.WeightField.ValueChangedFcn = createCallbackFcn(app, @WeightFieldValueChanged, true);
			app.WeightField.ValueChangingFcn = createCallbackFcn(app, @WeightFieldValueChanging, true);
			app.MaternalHistoryofFracturesLabel = uilabel(app.UIFigure);
			app.MaternalHistoryofFracturesLabel.Text = 'Maternal History of Fractures?';
			app.MaternalHistoryofFracturesDropDown = uidropdown(app.UIFigure);
			app.MaternalHistoryofFracturesDropDown.Items = {'No', 'Yes'};
			app.MaternalHistoryofFracturesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MaternalHistoryofFracturesDropDownOpening, true);
			app.MaternalHistoryofFracturesDropDown.Value = 'No';
			app.VitaminDeficiencyLabel = uilabel(app.UIFigure);
			app.VitaminDeficiencyLabel.Text = 'Vitamin Deficiency?';
			app.VitaminDeficiencyDropDown = uidropdown(app.UIFigure);
			app.VitaminDeficiencyDropDown.Items = {'No', 'Yes'};
			app.VitaminDeficiencyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VitaminDeficiencyDropDownOpening, true);
			app.VitaminDeficiencyDropDown.Value = 'No';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = mainguiosteporosispredictor

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end