classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        SUBMITButton                   matlab.ui.control.Button
        HawaiiHIVResourceLocatorLabel  matlab.ui.control.Label
        TreatmentTypeDropDown          matlab.ui.control.DropDown
        TreatmentTypeDropDownLabel     matlab.ui.control.Label
        NumberofDesiredResultsEditField  matlab.ui.control.NumericEditField
        NumberofDesiredResultsEditFieldLabel  matlab.ui.control.Label
        ZipcodeEditField               matlab.ui.control.NumericEditField
        ZipcodeEditFieldLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            if app.ZipcodeEditField.Value == 0
                msg = "Error loading results. Please input valid zipcode.";
                error(msg)
            end
            
            dbfile=fullfile(pwd, 'HIV Treatment.db');
            conn = sqlite(dbfile);
            
            if app.TreatmentTypeDropDown.Value == "Testing"
                rs = fetch (conn, "SELECT *" + ...
                    "FROM  Resources " + ...
                    "WHERE Treatment= 'Testing' OR Treatment= 'All'" + ...
                    " ORDER BY Zipcode");
            elseif app.TreatmentTypeDropDown.Value == "All"
                rs = fetch (conn, "SELECT *" + ...
                    "FROM  Resources " + ...
                    "WHERE Treatment= 'All'" + ...
                    " ORDER BY Zipcode");
            elseif app.TreatmentTypeDropDown.Value == "Treatment"
                rs = fetch (conn, "SELECT *" + ...
                    "FROM  Resources " + ...
                    "WHERE Treatment= 'Treatment'OR Treatment= 'All'" + ...
                    " ORDER BY Zipcode");
            end
            zip = app.ZipcodeEditField.Value;
            rs= cell2table(rs);
            zips=rs(:,2);
            zips = zips{:,:};
            [minDistance, indexOfMin] = min(abs(zips-zip));
            rows=ceil(app.NumberofDesiredResultsEditField.Value/2);
            ans = [];
            if indexOfMin-rows<= 1
                z=indexOfMin:indexOfMin+app.NumberofDesiredResultsEditField.Value;
            elseif indexOfMin+rows >=51
                z=indexOfMin:indexOfMin-app.NumberofDesiredResultsEditField.Value;
            else 
                z=indexOfMin-rows:indexOfMin+rows;
            end
            for i=z
                T=ans;
                t=rs(i,:);
                ans= [T;t];
            end
            ans = head(ans,app.NumberofDesiredResultsEditField.Value);
            answer=table2cell(ans);
            app.UITable.Data = ans;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Hawaii.png';
            app.ZipcodeEditFieldLabel = uilabel(app.UIFigure);
            app.ZipcodeEditFieldLabel.Text = 'Zipcode';
            app.ZipcodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZipcodeEditField.Limits = [0 Inf];
            app.ZipcodeEditField.ValueDisplayFormat = '%.0f';
            app.NumberofDesiredResultsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDesiredResultsEditFieldLabel.Text = 'Number of Desired Results';
            app.NumberofDesiredResultsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDesiredResultsEditField.Limits = [0 51];
            app.TreatmentTypeDropDownLabel = uilabel(app.UIFigure);
            app.TreatmentTypeDropDownLabel.Text = 'Treatment Type';
            app.TreatmentTypeDropDown = uidropdown(app.UIFigure);
            app.TreatmentTypeDropDown.Items = {'All', 'Testing', 'Treatment'};
            app.TreatmentTypeDropDown.Value = 'All';
            app.HawaiiHIVResourceLocatorLabel = uilabel(app.UIFigure);
            app.HawaiiHIVResourceLocatorLabel.Text = 'Hawaii HIV Resource Locator';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Zipcode'; 'Phone'; 'Treatment'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end