classdef mainGUI_breastcancerrisk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bcriskGUIUIFigure              matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        errorLabel                     matlab.ui.control.Label
        CalculateRiskButton            matlab.ui.control.Button
        biradsDropDown                 matlab.ui.control.DropDown
        BIRADSbreastdensityDropDownLabel  matlab.ui.control.Label
        birthDropDown                  matlab.ui.control.DropDown
        AgeoffirstbirthDropDownLabel   matlab.ui.control.Label
        menarcheDropDown               matlab.ui.control.DropDown
        AgeoffirstmenstrualcycleDropDownLabel  matlab.ui.control.Label
        fdhistoryDropDown              matlab.ui.control.DropDown
        AnyfirstdegreerelativewithhistoryofbreastcancerDropDownLabel  matlab.ui.control.Label
        menopauseDropDown              matlab.ui.control.DropDown
        MenopausalstatusDropDownLabel  matlab.ui.control.Label
        hormoneDropDown                matlab.ui.control.DropDown
        CurrentlyundergoinghormonereplacementtherapyDropDownLabel  matlab.ui.control.Label
        bmiDropDown                    matlab.ui.control.DropDown
        BMIkgm2Label                   matlab.ui.control.Label
        raceDropDown                   matlab.ui.control.DropDown
        RaceEthnicityDropDownLabel     matlab.ui.control.Label
        ageDropDown                    matlab.ui.control.DropDown
        AgeDropDownLabel               matlab.ui.control.Label
        subtitleTextArea               matlab.ui.control.TextArea
        bcribbonImage                  matlab.ui.control.Image
        titleLabel                     matlab.ui.control.Label
        disclaimerLabel                matlab.ui.control.Label
        pieUIAxes                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % by Landon Leininger, Isabella Spinelli, Demetri Tsistios
            % This is a breast cancer GUI that allows users to input 9
            % different risk factors corresponding to breast cancer risk,
            % and it outputs the user's risk of breast cancer based on data
            % taken from BCSC wesbite. 

            % hide the pie chart as it is not needed at this point
            % in time
            app.pieUIAxes.Visible = 'off';
            app.errorLabel.Visible = 'off';
           

        end

        % Button pushed function: CalculateRiskButton
        function CalculateRiskButtonPushed(app, event)
            % establish to database connection, inside the database
            % connection, if dbfile doesn't exist, it will download the
            % files and create the databse
            dbfile = [bmes.tempdir() '/breastcancerdb.sqlite'];
            conn = dbconnection(dbfile);
            
            % first need to define all the different inputs to be used
            % outside matlab function to calculate the risk factors
           
            age = app.ageDropDown.Value;
            race = app.raceDropDown.Value;
            fdhistory = app.fdhistoryDropDown.Value;
            menarche = app.menarcheDropDown.Value;
            birth_age = app.birthDropDown.Value;
            birads = app.biradsDropDown.Value;
            hormone_therapy = app.hormoneDropDown.Value;
            menopause = app.menopauseDropDown.Value;
            bmi = app.bmiDropDown.Value;

            %storing values in a cell array and field names in the fs
            %variable

            vals = {age,race,fdhistory,menarche,birth_age,birads,hormone_therapy,menopause,bmi};
            fs = {'age_group_5_years','race_eth','first_degree_hx','age_menarche','age_first_birth','BIRADS_breast_density','current_hrt','menopaus','bmi_group'};

            % calling the outside matlab function for risk calculation 
            [percentages] = riskcalc(conn,fs,vals);
            
            % Display an error message if the user doesn't input any
            % dropdown selections
            if isempty(percentages)
                
                app.pieUIAxes.Visible = 'off';
                app.errorLabel.Visible = 'on';

            else
                % plotting the pie chart inside the GUI for patient information
                app.errorLabel.Visible = 'off';
                app.pieUIAxes.Visible = 'on';
    
                pie(app.pieUIAxes,percentages);
                labels = {'% With Breast Cancer History','% Without Breast Cancer History','% Unknown Breast Cancer History'};
                legend(app.pieUIAxes,labels,'Location','best','Orientation','vertical')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.bcriskGUIUIFigure = uifigure('Visible', 'off');
            app.bcriskGUIUIFigure.Name = 'bcriskGUI';
            app.pieUIAxes = uiaxes(app.bcriskGUIUIFigure);
            xlabel(app.pieUIAxes, 'X')
            ylabel(app.pieUIAxes, 'Y')
            zlabel(app.pieUIAxes, 'Z')
            app.disclaimerLabel = uilabel(app.bcriskGUIUIFigure);
            app.disclaimerLabel.Text = '**DISCLAIMER: Our predictions of risk are based on the dataset found below.';
            app.titleLabel = uilabel(app.bcriskGUIUIFigure);
            app.titleLabel.Text = {'Find Out What Percentage of US Women With Your '; 'Risk Factors Have a History of Breast Cancer'};
            app.bcribbonImage = uiimage(app.bcriskGUIUIFigure);
            app.bcribbonImage.ImageSource = fullfile(pathToMLAPP, 'thumb.png');
            app.subtitleTextArea = uitextarea(app.bcriskGUIUIFigure);
            app.subtitleTextArea.Value = {'Breast Cancer Risk'};
            app.AgeDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.AgeDropDownLabel.Text = 'Age';
            app.ageDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.ageDropDown.Items = {' ', '1 = Age 18-29', '2 = Age 30-34', '3 = Age 35-39', '4 = Age 40-44', '5 = Age 45-49', '6 = Age 50-54', '7 = Age 55-59', '8 = Age 60-64', '9 = Age 65-69', '10 = Age 70-74', '11 = Age 75-79', '12 = Age 80-84', '13 = Age >85'};
            app.ageDropDown.ItemsData = {'', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.ageDropDown.Value = '';
            app.RaceEthnicityDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.RaceEthnicityDropDownLabel.Text = 'Race/Ethnicity';
            app.raceDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.raceDropDown.Items = {'', '1 = Non-Hispanic white', '2 = Non-Hispanic black', '3 = Asian/Pacific Islander', '4 = Native American', '5 = Hispanic', '6 = Other/mixed', '9 = Unknown'};
            app.raceDropDown.ItemsData = {'', '1', '2', '3', '4', '5', '6', '9'};
            app.raceDropDown.Value = '';
            app.BMIkgm2Label = uilabel(app.bcriskGUIUIFigure);
            app.BMIkgm2Label.Text = 'BMI (kg/m^2)';
            app.bmiDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.bmiDropDown.Items = {'', '1 = 10-24.99', '2 = 25-29.99', '3 = 30-34.99', '4 = 35 or more', '9 = Unknown'};
            app.bmiDropDown.ItemsData = {'', '1', '2', '3', '4', '9'};
            app.bmiDropDown.Value = '';
            app.CurrentlyundergoinghormonereplacementtherapyDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.CurrentlyundergoinghormonereplacementtherapyDropDownLabel.Text = 'Currently undergoing hormone replacement therapy?';
            app.hormoneDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.hormoneDropDown.Items = {'', '0 = No', '1 = Yes', '9 = Unknown'};
            app.hormoneDropDown.ItemsData = {'', '0', '1', '9'};
            app.hormoneDropDown.Value = '';
            app.MenopausalstatusDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.MenopausalstatusDropDownLabel.Text = 'Menopausal status';
            app.menopauseDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.menopauseDropDown.Items = {'', '1 = Pre- or peri-menopausal', '2 = Post-menopausal', '3 = Surgical menopause', '9 = Unknown'};
            app.menopauseDropDown.ItemsData = {'', '1', '2', '3', '9'};
            app.menopauseDropDown.Value = '';
            app.AnyfirstdegreerelativewithhistoryofbreastcancerDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.AnyfirstdegreerelativewithhistoryofbreastcancerDropDownLabel.Text = {'Any first degree relative with history of breast cancer?'; ''};
            app.fdhistoryDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.fdhistoryDropDown.Items = {'', '0 = No', '1 = Yes', '9 = Unknown'};
            app.fdhistoryDropDown.ItemsData = {'', '0', '1', '9'};
            app.fdhistoryDropDown.Value = '';
            app.AgeoffirstmenstrualcycleDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.AgeoffirstmenstrualcycleDropDownLabel.Text = 'Age of first menstrual cycle';
            app.menarcheDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.menarcheDropDown.Items = {'', '0 = Age >14', '1 = Age 12-13', '2 = Age <12', '9 = Unknown'};
            app.menarcheDropDown.ItemsData = {'', '0', '1', '2', '9'};
            app.menarcheDropDown.Value = '';
            app.AgeoffirstbirthDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.AgeoffirstbirthDropDownLabel.Text = 'Age of first birth';
            app.birthDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.birthDropDown.Items = {'', '0 = Age < 20', '1 = Age 20-24', '2 = Age 25-29', '3 = Age >30', '4 = Nulliparous', '9 = Unknown'};
            app.birthDropDown.ItemsData = {'', '0', '1', '2', '3', '4', '9'};
            app.birthDropDown.Value = '';
            app.BIRADSbreastdensityDropDownLabel = uilabel(app.bcriskGUIUIFigure);
            app.BIRADSbreastdensityDropDownLabel.Text = 'BI-RADS breast density';
            app.biradsDropDown = uidropdown(app.bcriskGUIUIFigure);
            app.biradsDropDown.Items = {'', '1 = Almost entirely fat', '2 = Scattered fibroglandular densities', '3 = Heterogeneously dense', '4 = Extremely dense', '9 = Unknown or different measurement system'};
            app.biradsDropDown.ItemsData = {'', '1', '2', '3', '4', '9'};
            app.biradsDropDown.Value = '';
            app.CalculateRiskButton = uibutton(app.bcriskGUIUIFigure, 'push');
            app.CalculateRiskButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRiskButtonPushed, true);
            app.CalculateRiskButton.Text = 'Calculate Risk';
            app.errorLabel = uilabel(app.bcriskGUIUIFigure);
            app.errorLabel.Text = 'Please Select Value From DropDown';
            app.Hyperlink = uihyperlink(app.bcriskGUIUIFigure);
            app.Hyperlink.URL = 'https://www.bcsc-research.org/data/rf';
            app.Hyperlink.Text = 'BCSC Risk Factors Dataset';
            % Show the figure after all components are created
            app.bcriskGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI_breastcancerrisk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bcriskGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bcriskGUIUIFigure)
        end
    end
end