classdef RiskFactorPhysician < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CurrentRiskValuePanel          matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        RecalculationPanel             matlab.ui.container.Panel
        ApplicableVariantsDropDown_2   matlab.ui.control.DropDown
        ApplicableVariantsDropDown_2Label  matlab.ui.control.Label
        ReplotButton                   matlab.ui.control.Button
        ExerciseHabitDropDown_2        matlab.ui.control.DropDown
        ExerciseHabitDropDown_2Label   matlab.ui.control.Label
        SmokingHistoryDropDown_2       matlab.ui.control.DropDown
        SmokingHistoryDropDown_2Label  matlab.ui.control.Label
        RiskGraphPanel                 matlab.ui.container.Panel
        RiskFactorsPanel               matlab.ui.container.Panel
        ApplicableVariantsDropDown     matlab.ui.control.DropDown
        ApplicableVariantsDropDownLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        ExerciseHabitDropDown          matlab.ui.control.DropDown
        ExerciseHabitDropDownLabel     matlab.ui.control.Label
        FamilyHistoryDropDown          matlab.ui.control.DropDown
        FamilyHistoryDropDownLabel     matlab.ui.control.Label
        SmokingHistoryPacksDropDown    matlab.ui.control.DropDown
        SmokingHistoryPacksDropDownLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RiskGraphPanel.Visible = 'off';
            app.RecalculationPanel.Visible = 'off';
            app.CurrentRiskValuePanel.Visible = 'off';
        end

        % Callback function
        function PushhereButtonPushed(app, event)

        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: ExerciseHabitDropDown
        function ExerciseHabitDropDownValueChanged(app, event)
  
        end

        % Callback function
        function EnterPatientIDEditFieldValueChanged(app, event)

        end

        % Callback function: PlotButton, UIAxes
        function PlotButtonPushed(app, event)
            phys = 1;
            age = app.AgeEditField.Value;
            smokinghistory = app.SmokingHistoryPacksDropDown.Value;
            familyhistory = app.FamilyHistoryDropDown.Value;
            exercise = app.ExerciseHabitDropDown.Value;
            gene = app.ApplicableVariantsDropDown.Value;
            [grapdata,risk_phys,~] = Riskcalculator(age,smokinghistory,familyhistory,exercise,gene,phys);
            x = [1:99];
            app.RiskGraphPanel.Visible = 'on';
            app.RecalculationPanel.Visible = 'on';
            app.UIAxes.XLim=([age,99]);
            plot(app.UIAxes,x,grapdata)
            hold(app.UIAxes,'on');
            app.CurrentRiskValuePanel.Visible = 'on';
            app.Label.Text = num2str(risk_phys);
        end

        % Value changed function: SmokingHistoryPacksDropDown
        function SmokingHistoryPacksDropDownValueChanged(app, event)

        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            phys = 1;
            age = app.AgeEditField.Value;
            smokinghistory2 = app.SmokingHistoryDropDown_2.Value;
            familyhistory = app.FamilyHistoryDropDown.Value;
            exercise2 = app.ExerciseHabitDropDown_2.Value;
            gene2 = app.ApplicableVariantsDropDown_2.Value;
            [grapdata2,~,~] = Riskcalculator(age,smokinghistory2,familyhistory,exercise2,gene2,phys);
            x = [1:99];
            plot(app.UIAxes,x,grapdata2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Risk Trends')
            xlabel(app.UIAxes, 'Age')
            ylabel(app.UIAxes, 'Risk Score')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.RiskFactorsPanel = uipanel(app.UIFigure);
            app.RiskFactorsPanel.Title = 'Risk Factors';
            app.SmokingHistoryPacksDropDownLabel = uilabel(app.RiskFactorsPanel);
            app.SmokingHistoryPacksDropDownLabel.Text = 'Smoking History (Packs)';
            app.SmokingHistoryPacksDropDown = uidropdown(app.RiskFactorsPanel);
            app.SmokingHistoryPacksDropDown.Items = {'Never', 'One a Year', 'One a Month', 'One a Week', 'One a Day'};
            app.SmokingHistoryPacksDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.SmokingHistoryPacksDropDown.ValueChangedFcn = createCallbackFcn(app, @SmokingHistoryPacksDropDownValueChanged, true);
            app.SmokingHistoryPacksDropDown.Placeholder = 'Never';
            app.SmokingHistoryPacksDropDown.Value = '0';
            app.FamilyHistoryDropDownLabel = uilabel(app.RiskFactorsPanel);
            app.FamilyHistoryDropDownLabel.Text = 'Family History';
            app.FamilyHistoryDropDown = uidropdown(app.RiskFactorsPanel);
            app.FamilyHistoryDropDown.Items = {'Yes', 'No'};
            app.FamilyHistoryDropDown.ItemsData = {'1', '0'};
            app.FamilyHistoryDropDown.Value = '0';
            app.ExerciseHabitDropDownLabel = uilabel(app.RiskFactorsPanel);
            app.ExerciseHabitDropDownLabel.Text = 'Exercise Habit';
            app.ExerciseHabitDropDown = uidropdown(app.RiskFactorsPanel);
            app.ExerciseHabitDropDown.Items = {'Never', '1', '2', '3', '4', '5', '6', '7'};
            app.ExerciseHabitDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.ExerciseHabitDropDown.ValueChangedFcn = createCallbackFcn(app, @ExerciseHabitDropDownValueChanged, true);
            app.ExerciseHabitDropDown.Value = '0';
            app.AgeEditFieldLabel = uilabel(app.RiskFactorsPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.RiskFactorsPanel, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.PlotButton = uibutton(app.RiskFactorsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ApplicableVariantsDropDownLabel = uilabel(app.RiskFactorsPanel);
            app.ApplicableVariantsDropDownLabel.Text = 'Applicable Variants';
            app.ApplicableVariantsDropDown = uidropdown(app.RiskFactorsPanel);
            app.ApplicableVariantsDropDown.Items = {'None', 'MUC16', 'TP53', 'TTN'};
            app.ApplicableVariantsDropDown.ItemsData = {'0', '1', '3', '2'};
            app.ApplicableVariantsDropDown.Value = '0';
            app.RiskGraphPanel = uipanel(app.UIFigure);
            app.RiskGraphPanel.Title = 'Risk Graph';
            app.RecalculationPanel = uipanel(app.UIFigure);
            app.RecalculationPanel.Title = 'Recalculation';
            app.SmokingHistoryDropDown_2Label = uilabel(app.RecalculationPanel);
            app.SmokingHistoryDropDown_2Label.Text = 'Smoking History';
            app.SmokingHistoryDropDown_2 = uidropdown(app.RecalculationPanel);
            app.SmokingHistoryDropDown_2.Items = {'Never', 'One a Day', 'One a Week', 'One a Month', 'One a Year'};
            app.SmokingHistoryDropDown_2.ItemsData = {'0', '1', '2', '3', '4'};
            app.SmokingHistoryDropDown_2.Placeholder = 'Never';
            app.SmokingHistoryDropDown_2.Value = '0';
            app.ExerciseHabitDropDown_2Label = uilabel(app.RecalculationPanel);
            app.ExerciseHabitDropDown_2Label.Text = 'Exercise Habit';
            app.ExerciseHabitDropDown_2 = uidropdown(app.RecalculationPanel);
            app.ExerciseHabitDropDown_2.Items = {'Never', '1', '2', '3', '4', '5', '6', '7'};
            app.ExerciseHabitDropDown_2.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.ExerciseHabitDropDown_2.Value = '0';
            app.ReplotButton = uibutton(app.RecalculationPanel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.ApplicableVariantsDropDown_2Label = uilabel(app.RecalculationPanel);
            app.ApplicableVariantsDropDown_2Label.Text = 'Applicable Variants';
            app.ApplicableVariantsDropDown_2 = uidropdown(app.RecalculationPanel);
            app.ApplicableVariantsDropDown_2.Items = {'None', 'MUC16', 'TP53', 'TTN'};
            app.ApplicableVariantsDropDown_2.ItemsData = {'0', '1', '3', '2'};
            app.ApplicableVariantsDropDown_2.Value = '0';
            app.CurrentRiskValuePanel = uipanel(app.UIFigure);
            app.CurrentRiskValuePanel.Title = 'Current Risk Value';
            app.Label = uilabel(app.CurrentRiskValuePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiskFactorPhysician

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end