classdef UserInput2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SummaryButton                   matlab.ui.control.Button
        RiskFactor                      matlab.ui.control.Label
        RiskEqualLabel                  matlab.ui.control.Label
        MonkeypoxRiskCalculatorLabel    matlab.ui.control.Label
        RegionalDataPanel               matlab.ui.container.Panel
        StateDropDown                   matlab.ui.control.DropDown
        StateDropDownLabel              matlab.ui.control.Label
        StateLabel                      matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        UsersDataPanel                  matlab.ui.container.Panel
        VaccinationDropDown             matlab.ui.control.DropDown
        VaccinationStatusDropDownLabel  matlab.ui.control.Label
        EthnicityDropDown               matlab.ui.control.DropDown
        EthnicityDropDownLabel          matlab.ui.control.Label
        GenderDropDown                  matlab.ui.control.DropDown
        GenderDropDownLabel             matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeLabel                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.callingapp = mainapp;
            app.RiskEqualLabel.Visible = 'off';
            app.RiskFactor.Visible = 'off';
            app.SummaryButton.Visible = 'off';

            % Check if the database exists, if not run the code to create
            % it
            if ~isfile("sql.sqlite")
                SQL;
            end


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

% Define Dropdown variables
state = app.StateDropDown.Value;
vax = app.VaccinationDropDown.Value;
ethnicity = app.EthnicityDropDown.Value;
gender = app.GenderDropDown.Value;
age = app.AgeDropDown.Value;

% Establish a connection with database
dbfile = [pwd '\sql.sqlite'];
conn = sqlite(dbfile);

%%%%% Grab values from Dimitri's SQL
% Check State
state_table = fetch(conn, 'SELECT * FROM region');
state_names = table2array(state_table(:,2));
state_cases = table2array(state_table(:,3));
i_age = find(state_names == state);
state_case = double(state_cases(i_age));
total_state = double(state_cases(find(state_names == "Total")));
state_case = state_case / total_state;

% Check Vaccination
vax_table = fetch(conn, 'SELECT * FROM vaccine');
vax_names = table2array(vax_table(:,2));
vax_cases = table2array(vax_table(:,3));
i_vax = find(vax_names == vax);
vax_case = double(vax_cases(i_vax));
total_vax = double(vax_cases(find(vax_names == "Total")));
vax_case = vax_case / total_vax;

% Check age + gender
    % Create table
age_table = fetch(conn, 'SELECT * FROM age');
age_names = table2array(age_table(:,2));

% Create header variable
if gender == "Man"
    header = "men";
elseif gender == "Transgender Man"
    header = "trans_men";
elseif gender == "Woman"
    header = "women";
elseif gender == "Transgender Woman"
    header = "trans_women";
elseif gender == "Others"
    header = "other";
end

% Find correct column
i_header = find(string(age_table.Properties.VariableNames) == header);
gender_col = double(table2array(age_table(:,i_header)));
total_gender = sum(gender_col);


% Find case in gender_col
i_age = find(age_names == age);
age_case = double(gender_col(i_age));
age_case = age_case / total_gender;

% Check Ethnicity

ethnicity_table = fetch(conn, 'SELECT * FROM ethnicity');
ethnicity_names = table2array(ethnicity_table(:,2));
ethnicity_percentage_array = table2array(ethnicity_table(:,3));

% Create header2 variable 
if ethnicity == "American Indian Or Alaska Native"
    header2 = "AmericanIndianOrAlaskaNative";
elseif ethnicity == "Asian"
    header2 = "Asian";
elseif ethnicity == "Black Or African American"
    header2 = "BlackOrAfricanAmerican";
elseif ethnicity == "Hispanic Or Latino"
    header2 = "HispanicOrLatino";
elseif ethnicity == "Multiple Races"
    header2 = "MultipleRaces";
elseif ethnicity == "Native Hawaiian or Pacific Islander"
    header2 = "NativeHawaiianOrOtherPacificIslander";
elseif ethnicity == "White"
    header2 = "White"
elseif ethnicity == "Other Race" 
    header2 = "OtherRace";
end

% Find correct column
i_header2 = find(header2 == ethnicity_names);
ethnicity_percentage = ethnicity_percentage_array(i_header2) / 100;


% Consolidate all user specific data into an array.
user_data = [state_case; vax_case; age_case; ethnicity_percentage];
user_input = {state; vax;  ethnicity ; gender ; age}; % Send to Madison's code

% Calculate Risk
risk = mean(user_data) * 100;

% Turn on risk labels
    app.RiskEqualLabel.Visible = 'on';
    app.RiskFactor.Visible = 'on';
    app.SummaryButton.Visible = 'on';
    a=sprintf('%.2f',risk);
% Color code font

if risk >= 0 && risk < 10
    app.RiskFactor.FontColor = [0.47 0.67 0.19];
elseif risk >= 10 && risk < 20
    app.RiskFactor.FontColor = [0.39 0.83 0.07];
elseif risk >= 20 && risk < 30
    app.RiskFactor.FontColor = [0 1 0]; 
elseif risk >= 30 && risk < 40
    app.RiskFactor.FontColor = [1.00 1.00 0.07];
elseif risk >= 40 && risk < 50
    app.RiskFactor.FontColor = [0.93 0.69 0.13];
elseif risk >= 50 && risk < 60
    app.RiskFactor.FontColor = [1.00 0.41 0.16];
elseif risk >= 60 && risk < 70
    app.RiskFactor.FontColor = [0.85 0.33 0.10];
elseif risk >= 70 && risk < 80
    app.RiskFactor.FontColor = [1.00 0.00 0.00];
elseif risk >= 80 && risk < 90
    app.RiskFactor.FontColor = [0.64 0.08 0.18];
elseif risk >= 80 && risk <=100
    app.RiskFactor.FontColor = [0.64 0.08 0.18];
end

% Print out risk percentage
app.RiskFactor.Text = [a '%'];


% Pass user_date to Madison's Summary Page 


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
delete(app)
          
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            state = app.StateDropDown.Value;
            
        end

        % Value changed function: VaccinationDropDown
        function VaccinationDropDownValueChanged(app, event)
            vax = app.VaccinationDropDown.Value;
            
        end

        % Value changed function: EthnicityDropDown
        function EthnicityDropDownValueChanged(app, event)
            ethnicity = app.EthnicityDropDown.Value;
            
        end

        % Value changed function: GenderDropDown
        function GenderDropDownValueChanged(app, event)
            gender = app.GenderDropDown.Value;
            
        end

        % Value changed function: AgeDropDown
        function AgeDropDownValueChanged(app, event)
            age = app.AgeDropDown.Value;
            
        end

        % Button pushed function: SummaryButton
        function SummaryButtonPushed(app, event)
            % Get current inputs from the choices
            state = app.StateDropDown.Value;
            vax = app.VaccinationDropDown.Value;
            ethnicity = app.EthnicityDropDown.Value;
            gender = app.GenderDropDown.Value;
            age = app.AgeDropDown.Value;

            % Call the stats GUI
            stats(age, gender, state, ethnicity, vax)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UsersDataPanel = uipanel(app.UIFigure);
            app.UsersDataPanel.Title = 'User''s Data';
            app.AgeLabel = uilabel(app.UsersDataPanel);
            app.AgeLabel.Text = 'Age';
            app.AgeDropDown = uidropdown(app.UsersDataPanel);
            app.AgeDropDown.Items = {'0 to 5', '6 to 10', '11 to 15', '16 to 20', '21 to 25', '26 to 30', '31 to 35', '36 to 40', '41 to 45', '46 to 50', '51 to 55', '56 to 60', '61 to 65', '66 to 70', '71 to 75', '76 + years'};
            app.AgeDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeDropDownValueChanged, true);
            app.AgeDropDown.Value = '0 to 5';
            app.GenderDropDownLabel = uilabel(app.UsersDataPanel);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.UsersDataPanel);
            app.GenderDropDown.Items = {'Man', 'Transgender Man', 'Woman', 'Transgender Woman', 'Others'};
            app.GenderDropDown.ValueChangedFcn = createCallbackFcn(app, @GenderDropDownValueChanged, true);
            app.GenderDropDown.Value = 'Man';
            app.EthnicityDropDownLabel = uilabel(app.UsersDataPanel);
            app.EthnicityDropDownLabel.Text = 'Ethnicity';
            app.EthnicityDropDown = uidropdown(app.UsersDataPanel);
            app.EthnicityDropDown.Items = {'American Indian Or Alaska Native', 'Asian', 'Black Or African American', 'Hispanic Or Latino', 'Multiple Races', 'Native Hawaiian or Pacific Islander', 'White', 'Other Race'};
            app.EthnicityDropDown.ValueChangedFcn = createCallbackFcn(app, @EthnicityDropDownValueChanged, true);
            app.EthnicityDropDown.Value = 'American Indian Or Alaska Native';
            app.VaccinationStatusDropDownLabel = uilabel(app.UsersDataPanel);
            app.VaccinationStatusDropDownLabel.Text = 'Vaccination Status';
            app.VaccinationDropDown = uidropdown(app.UsersDataPanel);
            app.VaccinationDropDown.Items = {'Vaccinated', 'Unvaccinated'};
            app.VaccinationDropDown.ValueChangedFcn = createCallbackFcn(app, @VaccinationDropDownValueChanged, true);
            app.VaccinationDropDown.Value = 'Vaccinated';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.RegionalDataPanel = uipanel(app.UIFigure);
            app.RegionalDataPanel.Title = 'Regional Data';
            app.StateLabel = uilabel(app.RegionalDataPanel);
            app.StateLabel.Text = 'Which state do you live in?';
            app.StateDropDownLabel = uilabel(app.RegionalDataPanel);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.RegionalDataPanel);
            app.StateDropDown.Items = {'Alabama', 'Alaska', 'Arizona', 'Arkansas', 'California', 'Colorado', 'Connecticut', 'Delaware', 'District of Columbia', 'Florida', 'Georgia', 'Hawaii', 'Idaho', 'Illinois', 'Indiana', 'Iowa', 'Kansas', 'Kentucky', 'Louisiana', 'Maine', 'Maryland', 'Massachusetts', 'Michigan', 'Minnesota', 'Mississippi', 'Missouri', 'Montana', 'Nebraska', 'Nevada', 'New Hampshire', 'New Jersey', 'New Mexico', 'New York', 'North Carolina', 'North Dakota', 'Ohio', 'Oklahoma', 'Oregon', 'Pennsylvania', 'Puerto Rico', 'Rhode Island', 'South Carolina', 'South Dakota', 'Tennessee', 'Texas', 'Utah', 'Vermont', 'Virginia', 'Washington', 'West Virginia', 'Wisconsin', 'Wyoming', 'Non U-S Resident'};
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.StateDropDown.Value = 'Alabama';
            app.MonkeypoxRiskCalculatorLabel = uilabel(app.UIFigure);
            app.MonkeypoxRiskCalculatorLabel.Text = 'Monkeypox Risk Calculator';
            app.RiskEqualLabel = uilabel(app.UIFigure);
            app.RiskEqualLabel.Text = 'Risk =';
            app.RiskFactor = uilabel(app.UIFigure);
            app.RiskFactor.Text = '';
            app.SummaryButton = uibutton(app.UIFigure, 'push');
            app.SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SummaryButtonPushed, true);
            app.SummaryButton.Text = 'Go to Summary Page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInput2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end