classdef stats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VaxRisk                       matlab.ui.control.Label
        RegRisk                       matlab.ui.control.Label
        EthRisk                       matlab.ui.control.Label
        GenRisk                       matlab.ui.control.Label
        AgeRisk                       matlab.ui.control.Label
        VaxStatLabel                  matlab.ui.control.TextArea
        RegionLabel                   matlab.ui.control.TextArea
        EthnicityLabel                matlab.ui.control.TextArea
        GenderLabel                   matlab.ui.control.TextArea
        AgeLabel                      matlab.ui.control.TextArea
        IndividualStatsHeader         matlab.ui.control.TextArea
        Cover                         matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        SelectParameter               matlab.ui.control.DropDown
        SelectParameterDropDownLabel  matlab.ui.control.Label
        DemographicPlot               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function PlotGraph(app, param, cat, data)
            if param == "Ethnicity"
                % Plotting the Etnicity data
                bar(app.DemographicPlot, data(1:end-1)')
                set(app.DemographicPlot, 'XTickLabel', cat(1:end-1)')
                app.DemographicPlot.YLim = [0 40];
                app.DemographicPlot.YLabel.String = "Percent of Cases";              
            elseif param == "Region"
                % Set up data
                max_data = data(length(data));
                data = 100*data(1:end-1)./max_data;

                % Set up abbreviations
                state_ab = {'Non-US' 'AL' 'AK' 'AZ' 'AR' 'CA' 'CO' 'CT' 'DE' 'DC' 'FL' 'GA' 'HI' 'ID' 'IL' 'IN' 'IA' 'KS' 'KY' 'LA' 'ME' 'MD' 'MA' 'MI' 'MN' 'MS' 'MO' 'MT' 'NE' 'NV' 'NH' 'NJ' 'NM' 'NY' 'NC' 'ND' 'OH' 'OK' 'OR' 'PA' 'PR' 'RI' 'SC' 'SD' 'TN' 'TX' 'UT' 'VT' 'VA' 'WA' 'WV' 'WI' 'WY'};

                % Plot the Data
                bar(app.DemographicPlot, data)
                set(app.DemographicPlot, 'XTick', 1:53, 'XTickLabel', state_ab', 'fontsize', 10)
                app.DemographicPlot.YLim = [0 20];
                app.DemographicPlot.YLabel.String = "Percent of Cases"; 
            elseif param == "Vaccination Status"
                % Create Percentages
                data = 100*data(1:2)/data(3);

                % Plotting the Results
                bar(app.DemographicPlot, data)
                set(app.DemographicPlot, 'XTickLabel', cat(1:2))
                app.DemographicPlot.YLim = [0 100];
                app.DemographicPlot.YLabel.String = "Percent of Cases"; 
            elseif param == "Age"
                % Finding the Precentages
                data = table2array(data(:,8));
                data = 100*data(1:end-1)/data(length(data));

                % Plotting the Data
                bar(app.DemographicPlot, data)
                set(app.DemographicPlot, 'XTick', 1:length(data), 'XTickLabel', cat(1:end-1), 'fontsize', 10)
                app.DemographicPlot.YLim = [0 30];
                app.DemographicPlot.YLabel.String = "Percent of Cases"; 

            elseif param == "Gender"
                % Finding the Percentages
                data = table2array(data(17,3:8))';
                data = 100*data(1:end-1)/data(end);

                % Setting up the label array
                labels = ["Other", "Men", "Trans Men", "Trans Women", "Women"];

                % Plotting the Data
                bar(app.DemographicPlot, data)
                set(app.DemographicPlot, 'XTick', 1:length(data), 'XTickLabel', labels, 'fontsize', 10)
                app.DemographicPlot.YLim = [0 100];
                app.DemographicPlot.YLabel.String = "Percent of Cases"; 

            end
        end
        
        function colorVal = colorCode(app, risk)
            % Color code font
            if risk >= 0 && risk < 10
                colorVal = [0.47 0.67 0.19];
            elseif risk >= 10 && risk < 20
                colorVal = [0.39 0.83 0.07];
            elseif risk >= 20 && risk < 30
                colorVal = [0 1 0]; 
            elseif risk >= 30 && risk < 40
                colorVal = [1.00 1.00 0.07];
            elseif risk >= 40 && risk < 50
                colorVal = [0.93 0.69 0.13];
            elseif risk >= 50 && risk < 60
                colorVal = [1.00 0.41 0.16];
            elseif risk >= 60 && risk < 70
                colorVal = [0.85 0.33 0.10];
            elseif risk >= 70 && risk < 80
                colorVal = [1.00 0.00 0.00];
            elseif risk >= 80 && risk < 90
                colorVal = [0.64 0.08 0.18];
            elseif risk >= 80 && risk <=100
                colorVal = [0.64 0.08 0.18];
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, age, gender, region, ethnicity, vax_stat)
            age_val = age;
            gen_val = gender;
            reg_val = region;
            eth_val = ethnicity;
            vax_val = vax_stat;
            
            % Establish a connection with database
            dbfile = [pwd '\sql.sqlite'];
            conn = sqlite(dbfile);
            
            %%%%% Grab values from Dimitri's SQL
            % Check State
            state_table = fetch(conn, 'SELECT * FROM region');
            state_names = table2array(state_table(:,2));
            state_cases = table2array(state_table(:,3));
            i_age = find(state_names == reg_val);
            state_case = double(state_cases(i_age));
            total_state = double(state_cases(find(state_names == "Total")));
            state_risk = 100 * state_case / total_state;

            % Color Code and Print out state risk
            app.RegRisk.FontColor = colorCode(app, state_risk);
            a=sprintf('%.2f',state_risk);
            app.RegRisk.Text = [a '%'];
            
            % Check Vaccination
            vax_table = fetch(conn, 'SELECT * FROM vaccine');
            vax_names = table2array(vax_table(:,2));
            vax_cases = table2array(vax_table(:,3));
            i_vax = find(vax_names == vax_val);
            vax_case = double(vax_cases(i_vax));
            total_vax = double(vax_cases(find(vax_names == "Total")));
            vax_risk = 100 * vax_case / total_vax;
            
            % Color Code and Print out vax risk
            app.VaxRisk.FontColor = colorCode(app, state_risk);
            a=sprintf('%.2f',vax_risk);
            app.VaxRisk.Text = [a '%'];

            % Check age + gender
            % Create table
            age_table = fetch(conn, 'SELECT * FROM age');
            age_names = table2array(age_table(:,2));
            
            % Create header variable
            if gen_val == "Man"
                header = "men";
            elseif gen_val == "Transgender Man"
                header = "trans_men";
            elseif gen_val == "Woman"
                header = "women";
            elseif gen_val == "Transgender Woman"
                header = "trans_women";
            elseif gen_val == "Others"
                header = "other";
            end
            
            % Find correct column
            i_header = find(string(age_table.Properties.VariableNames) == header);
            gender_col = double(table2array(age_table(:,i_header)));
            total_gender = sum(gender_col);
            
            % Find Gender risk
            gen_tot = gender_col(length(gender_col));
            total_case = double(table2array(age_table(height(age_table), width(age_table))));
            gen_risk = 100 * gen_tot / total_case;

            % Color Code and Print out gender risk
            app.GenRisk.FontColor = colorCode(app, gen_risk);
            a=sprintf('%.2f',gen_risk);
            app.GenRisk.Text = [a '%'];

            % Find age risk
            i_age = find(age_names == age_val);
            age_case = double(gender_col(i_age));
            age_tot = double(table2array(age_table(i_age, 8)));
            age_risk = 100 * age_case / age_tot;

            % Color Code and Print out age risk
            app.AgeRisk.FontColor = colorCode(app, age_risk);
            a=sprintf('%.2f',age_risk);
            app.AgeRisk.Text = [a '%'];
            
            % Check Ethnicity
            ethnicity_table = fetch(conn, 'SELECT * FROM ethnicity');
            ethnicity_names = table2array(ethnicity_table(:,2));
            ethnicity_percentage_array = table2array(ethnicity_table(:,3));
            
            % Create header2 variable 
            if eth_val == "American Indian Or Alaska Native"
                header2 = "AmericanIndianOrAlaskaNative";
            elseif eth_val == "Asian"
                header2 = "Asian";
            elseif eth_val == "Black Or African American"
                header2 = "BlackOrAfricanAmerican";
            elseif eth_val == "Hispanic Or Latino"
                header2 = "HispanicOrLatino";
            elseif eth_val == "Multiple Races"
                header2 = "MultipleRaces";
            elseif eth_val == "Native Hawaiian or Pacific Islander"
                header2 = "NativeHawaiianOrOtherPacificIslander";
            elseif eth_val == "White"
                header2 = "White"
            elseif eth_val == "Other Race" 
                header2 = "OtherRace";
            end
            
            % Find correct column
            i_header2 = find(header2 == ethnicity_names);
            ethnicity_percentage = ethnicity_percentage_array(i_header2);

            % Color Code and Print out ethnicity risk
            app.EthRisk.FontColor = colorCode(app, ethnicity_percentage);
            a=sprintf('%.2f',ethnicity_percentage);
            app.EthRisk.Text = [a '%'];

        end

        % Value changed function: SelectParameter
        function SelectParameterValueChanged(app, event)
            % Finding what choice was made
            param = app.SelectParameter.Value;

            % If the chosen parameter is "Select Parameter", hide the panel
            if param == "Select Parameter"
                app.Cover.Visible = "on";
            else
                % Make the Panel Visible
                app.Cover.Visible = "off";

                % Change the title
                app.DemographicPlot.Title.String = param;
                   
                % Establish a connection with database
                dbfile = [pwd '\sql.sqlite'];
                conn = sqlite(dbfile);
                
                %%%%% Grab values from Dimitri's SQL
                % Check State
                state_table = fetch(conn, 'SELECT * FROM region');
                state_names = table2array(state_table(:,2));
                state_cases = table2array(state_table(:,3));
                
                % Check Vaccination
                vax_table = fetch(conn, 'SELECT * FROM vaccine');
                vax_names = table2array(vax_table(:,2));
                vax_cases = table2array(vax_table(:,3));
                
                % Check age + gender
                age_table = fetch(conn, 'SELECT * FROM age');
                age_names = table2array(age_table(:,2));
                
                % Check Ethnicity
                ethnicity_table = fetch(conn, 'SELECT * FROM ethnicity');
                ethnicity_names = table2array(ethnicity_table(:,2));
                ethnicity_percentage_array = table2array(ethnicity_table(:,3));
    
                % Call the function to plot the data
                if param == "Age" || param == "Gender"
                    PlotGraph(app, param, age_names, age_table)
                elseif param == "Ethnicity"
                    PlotGraph(app, param, ethnicity_names, ethnicity_percentage_array)
                elseif param == "Region"
                    PlotGraph(app, param, state_names, state_cases)
                else
                    PlotGraph(app, param, vax_names, vax_cases)
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DemographicPlot = uiaxes(app.UIFigure);
            title(app.DemographicPlot, 'Age')
            ylabel(app.DemographicPlot, 'Y')
            zlabel(app.DemographicPlot, 'Z')
            app.SelectParameterDropDownLabel = uilabel(app.UIFigure);
            app.SelectParameterDropDownLabel.Text = 'Select Parameter';
            app.SelectParameter = uidropdown(app.UIFigure);
            app.SelectParameter.Items = {'Select Parameter', 'Age', 'Gender', 'Ethnicity', 'Region', 'Vaccination Status'};
            app.SelectParameter.ValueChangedFcn = createCallbackFcn(app, @SelectParameterValueChanged, true);
            app.SelectParameter.Value = 'Select Parameter';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Summary Statistics'};
            app.Cover = uitextarea(app.UIFigure);
            app.IndividualStatsHeader = uitextarea(app.UIFigure);
            app.IndividualStatsHeader.Value = {'Base Statistics Based on Your Input'; '(Demographic Count/Total Cases)'};
            app.AgeLabel = uitextarea(app.UIFigure);
            app.AgeLabel.Value = {'Age:'};
            app.GenderLabel = uitextarea(app.UIFigure);
            app.GenderLabel.Value = {'Gender:'};
            app.EthnicityLabel = uitextarea(app.UIFigure);
            app.EthnicityLabel.Value = {'Ethnicity:'};
            app.RegionLabel = uitextarea(app.UIFigure);
            app.RegionLabel.Value = {'Region:'};
            app.VaxStatLabel = uitextarea(app.UIFigure);
            app.VaxStatLabel.Value = {'Vaccination Status:'};
            app.AgeRisk = uilabel(app.UIFigure);
            app.GenRisk = uilabel(app.UIFigure);
            app.EthRisk = uilabel(app.UIFigure);
            app.RegRisk = uilabel(app.UIFigure);
            app.VaxRisk = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stats(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end