classdef cholesterol_tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Results                     matlab.ui.container.Panel
        Recommendations             matlab.ui.container.Panel
        recommendation3             matlab.ui.control.Hyperlink
        recommendation2             matlab.ui.control.Hyperlink
        recommendation1             matlab.ui.control.Hyperlink
        RiskAssessment              matlab.ui.container.Panel
        RiskHeartDisease            matlab.ui.control.EditField
        YourriskforEditFieldLabel   matlab.ui.control.Label
        cholesterol                 matlab.ui.container.Panel
        HDL_Chol_Results            matlab.ui.control.EditField
        HDLCholesterolLabel         matlab.ui.control.Label
        LDL_Chol_Results            matlab.ui.control.EditField
        LDLCholesterolLabel         matlab.ui.control.Label
        Tot_Chol_Results            matlab.ui.control.EditField
        TotalCholesterolLabel       matlab.ui.control.Label
        History                     matlab.ui.control.UIAxes
        Medication                  matlab.ui.control.DropDown
        AreyoutakingmedicationforbloodpressureDropDownLabel  matlab.ui.control.Label
        smoker                      matlab.ui.control.DropDown
        DoyousmokeDropDownLabel     matlab.ui.control.Label
        Title                       matlab.ui.control.Label
        Diabetes                    matlab.ui.control.DropDown
        DiabetesDropDownLabel       matlab.ui.control.Label
        Submit                      matlab.ui.control.Button
        Diastolic                   matlab.ui.control.NumericEditField
        DiastolicBloodPressuremmHgEditFieldLabel  matlab.ui.control.Label
        Systolic                    matlab.ui.control.NumericEditField
        SystolicBloodPressuremmHgEditFieldLabel  matlab.ui.control.Label
        Triglycerides               matlab.ui.control.NumericEditField
        TriglyceridesmgDLLabel      matlab.ui.control.Label
        TotalCholesterol            matlab.ui.control.NumericEditField
        TotalCholesterolmgdLEditFieldLabel  matlab.ui.control.Label
        HDL                         matlab.ui.control.NumericEditField
        HDLmgdLEditFieldLabel       matlab.ui.control.Label
        LDL                         matlab.ui.control.NumericEditField
        LDLmgdLEditFieldLabel       matlab.ui.control.Label
        DateofBirth                 matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel  matlab.ui.control.Label
        Gender                      matlab.ui.control.DropDown
        GenderDropDownLabel         matlab.ui.control.Label
    end

    % Jess Baggett, Grace Fan, Majo Garcia
    properties (Access = public)
        username=''; % empty variable that is the carryover from the login GUI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, username)
            app.username=username;
        end

        % Button pushed function: Submit
        function SubmitButtonPushed(app, event)
            %% make results visible
            app.Results.Visible='on';
            %% find patient age
            %current date
            c_date=datetime('today');

            %difference between date of birth and current date. Only care
            %about years. Output as string not calender duration
            difference=string(between(app.DateofBirth.Value,c_date,'years'));
            %remove y from string
            age=str2double(regexp(difference,'[\d.]+','match'));

            %% find risk of heart disease using heart_risk.m function
            app.RiskHeartDisease.Value=heart_risk(age, app.Gender.Value, app.smoker.Value, app.TotalCholesterol.Value, app.HDL.Value, app.Systolic.Value, app.Medication.Value);

            %% Run function recommendations.m to find cholesterol levels and recommendations
            [tot_chol_lvl, ldl_lvl, hdl_lvl, recom1, recom2, recom3]=recommendations(age, app.Gender.Value, app.TotalCholesterol.Value, app.LDL.Value, app.HDL.Value, app.Diabetes.Value);

            %% determine levels of cholesterol using recommendations.m function
            %display total cholesterol level
            app.Tot_Chol_Results.Value=tot_chol_lvl;

            %% sort total cholesterol levels by color so user can visualize results
            if strcmpi(tot_chol_lvl, 'high')
                app.Tot_Chol_Results.BackgroundColor=[1 0 0];
            elseif strcmpi(tot_chol_lvl, 'borderline high')
                app.Tot_Chol_Results.BackgroundColor=[1 0.7 0];
            else
                app.Tot_Chol_Results.BackgroundColor=[0.4 0.9 0.4];
            end

            %display LDL cholesterol level
            app.LDL_Chol_Results.Value=ldl_lvl;

            %% sort ldl cholesterol levels by color so user can visualize results
            if strcmpi(ldl_lvl, 'high')
                app.LDL_Chol_Results.BackgroundColor=[1 0 0];
            elseif strcmpi(ldl_lvl, 'borderline high')
                app.LDL_Chol_Results.BackgroundColor=[1 0.7 0];
            else
                app.LDL_Chol_Results.BackgroundColor=[0.4 0.9 0.4];
            end

            %display HDL cholesterol level
            app.HDL_Chol_Results.Value=hdl_lvl;

            %% sort hdl cholesterol levels by color so user can visualize results
            if strcmpi(hdl_lvl, 'high')
                app.HDL_Chol_Results.BackgroundColor=[1 0 0];
            elseif strcmpi(hdl_lvl, 'borderline high')
                app.HDL_Chol_Results.BackgroundColor=[1 0.7 0];
            else
                app.HDL_Chol_Results.BackgroundColor=[0.4 0.9 0.4];
            end

            %% Provide recommendations based on cholesterol levels and diabetes statues using recommendations.m function
            app.recommendation1.URL=recom1;
            app.recommendation2.URL=recom2;
            app.recommendation3.URL=recom3;

            %% History
            % store data and retrieve it
            % setting date in different format
            t=datetime('today','Format',"yyyy-MM-dd");
            user=app.username;
            output=history(user, app.TotalCholesterol.Value, app.LDL.Value, app.HDL.Value, app.Triglycerides.Value,  t);

            % plot
            % convert input_date into datetime 
            for i=1:numel(output.input_date)
                newt(i)=datetime(output.input_date(i));
            end
            
            % plots the total chol, LDL, HDL on same figure
            hold(app.History, 'on')
            p1=plot(app.History, newt,output.total_chol);
            p2=plot(app.History,newt,output.LDL);
            p3=plot(app.History,newt,output.HDL);
            legend(app.History,'Total','LDL','HDL','Location','southeast','NumColumns',3);
            hold(app.History, 'off')
            % creates datatips for all data points
%             for i=1:numel(output.input_date)
%                 datatip(p1,newt(i),output.total_chol(i));
%                 datatip(p2,newt(i),output.LDL(i));
%                 datatip(p3,newt(i),output.HDL(i));
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenderDropDownLabel = uilabel(app.UIFigure);
            app.GenderDropDownLabel.Text = 'Gender';
            app.Gender = uidropdown(app.UIFigure);
            app.Gender.Items = {'Female', 'Male'};
            app.Gender.Value = 'Female';
            app.DateofBirthDatePickerLabel = uilabel(app.UIFigure);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirth = uidatepicker(app.UIFigure);
            app.LDLmgdLEditFieldLabel = uilabel(app.UIFigure);
            app.LDLmgdLEditFieldLabel.Text = 'LDL (mg/dL)';
            app.LDL = uieditfield(app.UIFigure, 'numeric');
            app.HDLmgdLEditFieldLabel = uilabel(app.UIFigure);
            app.HDLmgdLEditFieldLabel.Text = 'HDL (mg/dL)';
            app.HDL = uieditfield(app.UIFigure, 'numeric');
            app.TotalCholesterolmgdLEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCholesterolmgdLEditFieldLabel.Text = 'Total Cholesterol (mg/dL)';
            app.TotalCholesterol = uieditfield(app.UIFigure, 'numeric');
            app.TriglyceridesmgDLLabel = uilabel(app.UIFigure);
            app.TriglyceridesmgDLLabel.Text = 'Triglycerides (mg/DL)';
            app.Triglycerides = uieditfield(app.UIFigure, 'numeric');
            app.SystolicBloodPressuremmHgEditFieldLabel = uilabel(app.UIFigure);
            app.SystolicBloodPressuremmHgEditFieldLabel.Text = 'Systolic Blood Pressure (mm Hg)';
            app.Systolic = uieditfield(app.UIFigure, 'numeric');
            app.DiastolicBloodPressuremmHgEditFieldLabel = uilabel(app.UIFigure);
            app.DiastolicBloodPressuremmHgEditFieldLabel.Text = 'Diastolic Blood Pressure (mm Hg)';
            app.Diastolic = uieditfield(app.UIFigure, 'numeric');
            app.Submit = uibutton(app.UIFigure, 'push');
            app.Submit.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.Submit.Text = 'Submit';
            app.DiabetesDropDownLabel = uilabel(app.UIFigure);
            app.DiabetesDropDownLabel.Text = 'Diabetes';
            app.Diabetes = uidropdown(app.UIFigure);
            app.Diabetes.Items = {'Yes', 'No'};
            app.Diabetes.Value = 'No';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Cholesterol Tracker';
            app.DoyousmokeDropDownLabel = uilabel(app.UIFigure);
            app.DoyousmokeDropDownLabel.Text = 'Do you smoke?';
            app.smoker = uidropdown(app.UIFigure);
            app.smoker.Items = {'No', 'Yes'};
            app.smoker.Value = 'No';
            app.AreyoutakingmedicationforbloodpressureDropDownLabel = uilabel(app.UIFigure);
            app.AreyoutakingmedicationforbloodpressureDropDownLabel.Text = 'Are you taking medication for blood pressure?';
            app.Medication = uidropdown(app.UIFigure);
            app.Medication.Items = {'No', 'Yes'};
            app.Medication.Value = 'No';
            app.Results = uipanel(app.UIFigure);
            app.Results.Title = 'Results';
            app.Results.Visible = 'off';
            app.History = uiaxes(app.Results);
            title(app.History, 'Title')
            xlabel(app.History, 'X')
            ylabel(app.History, 'Y')
            zlabel(app.History, 'Z')
            app.cholesterol = uipanel(app.Results);
            app.cholesterol.Title = 'Cholesterol Summary';
            app.TotalCholesterolLabel = uilabel(app.cholesterol);
            app.TotalCholesterolLabel.Text = 'Total Cholesterol:';
            app.Tot_Chol_Results = uieditfield(app.cholesterol, 'text');
            app.LDLCholesterolLabel = uilabel(app.cholesterol);
            app.LDLCholesterolLabel.Text = 'LDL Cholesterol:';
            app.LDL_Chol_Results = uieditfield(app.cholesterol, 'text');
            app.HDLCholesterolLabel = uilabel(app.cholesterol);
            app.HDLCholesterolLabel.Text = 'HDL Cholesterol:';
            app.HDL_Chol_Results = uieditfield(app.cholesterol, 'text');
            app.RiskAssessment = uipanel(app.Results);
            app.RiskAssessment.Title = 'Risk Assessment';
            app.YourriskforEditFieldLabel = uilabel(app.RiskAssessment);
            app.YourriskforEditFieldLabel.Text = 'Your risk for Hard Coronary Heart Disease in the next 10 years is';
            app.RiskHeartDisease = uieditfield(app.RiskAssessment, 'text');
            app.Recommendations = uipanel(app.Results);
            app.Recommendations.Title = 'Recommendations';
            app.recommendation1 = uihyperlink(app.Recommendations);
            app.recommendation1.Text = 'Recommendation 1';
            app.recommendation2 = uihyperlink(app.Recommendations);
            app.recommendation2.Text = 'Recommendation 2';
            app.recommendation3 = uihyperlink(app.Recommendations);
            app.recommendation3.Text = 'Recommendation 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cholesterol_tracker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end