classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UserLogin                 matlab.ui.Figure
        Error                     matlab.ui.control.Label
        NewUser                   matlab.ui.control.Button
        ExistingUser              matlab.ui.control.Button
        Password                  matlab.ui.control.EditField
        Password10charlimitLabel  matlab.ui.control.Label
        Title                     matlab.ui.control.Label
        Username                  matlab.ui.control.EditField
        UsernameLabel             matlab.ui.control.Label
    end

    %Jess Baggett, Grace Fan, Majo Garcia
    properties (Access = public)
        db_file=''; % create empty variable for the database file so it can be referenced later.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %see if database file exists if not create .db file.
            app.db_file=[bmes.tempdir() '/userinfo.db'];
            if ~isfile(app.db_file)
                fid=fopen(app.db_file,'w');
                conn=sqlite(app.db_file);
                %create tables
                execute(conn,'CREATE TABLE users (id INTEGER PRIMARY KEY, username VARCHAR(15), password VARCHAR(100))');
                execute(conn, 'CREATE TABLE data (id INTEGER PRIMARY KEY, username VARCHAR(15), total_chol INTEGER, LDL INTEGER, HDL INTEGER, Triglycerides INTEGER, input_date DATE)');
            end
        end

        % Button pushed function: ExistingUser
        function ExistingUserButtonPushed(app, event)
           % getting the encrypted password
            pswrd=encrypt(app.Password.Value);
            %% checking if password matches that from database
            %connect to database
            conn=sqlite(app.db_file);
            %creating the query. Need to add double quotes aroud username
            %for query to work
            name=['"' app.Username.Value '"'];
            %adding the username to the query
            q=append('SELECT username, password FROM users WHERE username=',name, 'OR password="',pswrd,'"');
            %fetching the password
            db_pw=fetch(conn,q);
            
            %if the inputted password and username don't match the password in the database
            %go to next GUI
            if isempty(db_pw)
                app.Error.Visible='on';
                app.Error.Text=sprintf('Incorrect password and/or username, please try again');
            elseif strcmp(db_pw.password{1},pswrd) && strcmp(db_pw.username{1},app.Username.Value)
                username=app.Username.Value;
                cholesterol_tracker(username)
                app.Error.Visible='off';

                %if it doesn't match show error message
            elseif ~strcmp(db_pw.password{1},pswrd) || ~strcmp(db_pw.username{1},app.Username.Value)
                app.Error.Visible='on';
                app.Error.Text=sprintf('Incorrect password and/or username, please try again');
            end
            
        end

        % Button pushed function: NewUser
        function NewUserButtonPushed(app, event)
            pswrd=encrypt(app.Password.Value);
            %see if username is already exists
            conn=sqlite(app.db_file);
            %creating the query. Need to add double quotes aroud username
            %for query to work
            name=['"' app.Username.Value '"'];
            %adding the username to the query
            q=append('SELECT id FROM users WHERE username=',name);
            %fetching the id
            id=fetch(conn,q);
            
            %if the user name exists give error message
            if ~isempty(id)
                app.Error.Visible='on';
                app.Error.Text=sprintf('Username taken, please try again');
            
                %if it doesn't exist add it to the table
            else
                %creating query for inserting username
                %put quotes around password
                pw=['"' pswrd '"'];
                q2=append('INSERT INTO users(username, password) VALUES(', name, ',', pw,')');
                execute(conn,q2)
                username=app.Username.Value;
                cholesterol_tracker(username)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UserLogin = uifigure('Visible', 'off');
            app.UserLogin.Name = 'MATLAB App';
            app.UsernameLabel = uilabel(app.UserLogin);
            app.UsernameLabel.Text = 'Username  (15 char. limit)';
            app.Username = uieditfield(app.UserLogin, 'text');
            app.Username.CharacterLimits = [0 15];
            app.Title = uilabel(app.UserLogin);
            app.Title.Text = 'Cholesterol Tracker Login';
            app.Password10charlimitLabel = uilabel(app.UserLogin);
            app.Password10charlimitLabel.Text = 'Password (10 char. limit)';
            app.Password = uieditfield(app.UserLogin, 'text');
            app.ExistingUser = uibutton(app.UserLogin, 'push');
            app.ExistingUser.ButtonPushedFcn = createCallbackFcn(app, @ExistingUserButtonPushed, true);
            app.ExistingUser.Text = 'Existing User';
            app.NewUser = uibutton(app.UserLogin, 'push');
            app.NewUser.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUser.Text = 'New User';
            app.Error = uilabel(app.UserLogin);
            app.Error.Visible = 'off';
            % Show the figure after all components are created
            app.UserLogin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UserLogin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UserLogin)
        end
    end
end