classdef Login_Screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ErrorMessage_Empty      matlab.ui.control.Label
        CysticFibrosisTracker   matlab.ui.control.Label
        NewUser                 matlab.ui.control.Button
        Password                matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        Username                matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        ErrorMessage            matlab.ui.control.Label
        Login                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Login
        function LoginButtonPushed(app, event)
            % By: Corine Lontoc, Soham Patel, Sam Schonwald
            
            % Defines username and password input
            User_In = app.Username.Value;
            Pass_In = app.Password.Value;
            % Opens a file text file that is used to encrypt the password
            % The text file with the password is deleted after encryption
            fileID = fopen('user_pass.txt','w');
            fprintf(fileID, Pass_In);
            Pass_In = md5('user_pass.txt');
            fclose('all');
            delete('user_pass.txt');
            
            % Shows an error message if either the username or password
            % fields are empty
            if isempty(app.Username.Value) || isempty(app.Password.Value)
                app.ErrorMessage_Empty.Visible = 'on';
                app.ErrorMessage.Visible = 'off';
            % If all fields are filled out, run the PassMatch function to
            % see if the username and password are correct
            else
                PassMatch(User_In, Pass_In);
            % If the username and password combination are correct, then
            % display the symptom survey and close the login screen
            if PassMatch(User_In, Pass_In) == 1
                Symptom_Survey(User_In);
                closereq();
            % If the username and password combination are incorrect, then
            % display an error message
            elseif PassMatch(User_In, Pass_In) == 0
                app.ErrorMessage.Visible = 'on';
                app.ErrorMessage_Empty.Visible = 'off';
            end
            end
            
        end

        % Button pushed function: NewUser
        function NewUserButtonPushed(app, event)
            User_Registration
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Login = uibutton(app.UIFigure, 'push');
            app.Login.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.Login.Text = 'Login';
            app.ErrorMessage = uilabel(app.UIFigure);
            app.ErrorMessage.Visible = 'off';
            app.ErrorMessage.Text = 'Username or Password Incorrect';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.Username = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.Password = uieditfield(app.UIFigure, 'text');
            app.NewUser = uibutton(app.UIFigure, 'push');
            app.NewUser.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUser.Text = 'New User?';
            app.CysticFibrosisTracker = uilabel(app.UIFigure);
            app.CysticFibrosisTracker.Text = 'Cystic Fibrosis Tracker';
            app.ErrorMessage_Empty = uilabel(app.UIFigure);
            app.ErrorMessage_Empty.Visible = 'off';
            app.ErrorMessage_Empty.Text = 'Please fill out all fields';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login_Screen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end