classdef Symptom_Survey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ErrorDate                      matlab.ui.control.Label
        ErrorNum                       matlab.ui.control.Label
        ErrorText                      matlab.ui.control.Label
        PreviousButton                 matlab.ui.control.Button
        SubmitButton                   matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        AppetiteLevel                  matlab.ui.control.NumericEditField
        AppetiteLevelEditFieldLabel    matlab.ui.control.Label
        StomachPain                    matlab.ui.control.NumericEditField
        StomachPainEditFieldLabel      matlab.ui.control.Label
        AmountofSputum                 matlab.ui.control.NumericEditField
        AmountofSputumEditFieldLabel   matlab.ui.control.Label
        LevelofActivity                matlab.ui.control.NumericEditField
        LevelofActivityEditFieldLabel  matlab.ui.control.Label
        EffortofBreathing              matlab.ui.control.NumericEditField
        EffortofBreathingEditFieldLabel  matlab.ui.control.Label
        CoughFrequency                 matlab.ui.control.NumericEditField
        CoughFrequencyEditFieldLabel   matlab.ui.control.Label
        LeftPanel                      matlab.ui.container.Panel
        Date                           matlab.ui.control.DatePicker
        DateDatePickerLabel            matlab.ui.control.Label
        StoolColor                     matlab.ui.control.EditField
        StoolColorEditFieldLabel       matlab.ui.control.Label
        StoolConsist                   matlab.ui.control.EditField
        StoolConsistencyLabel          matlab.ui.control.Label
        SputumColor                    matlab.ui.control.EditField
        SputumColorEditFieldLabel      matlab.ui.control.Label
        Weight                         matlab.ui.control.NumericEditField
        WeightlbEditFieldLabel         matlab.ui.control.Label
        Height                         matlab.ui.control.NumericEditField
        HeightinEditFieldLabel         matlab.ui.control.Label
        Title                          matlab.ui.control.Label
    end

    % By: Corine Lontoc, Soham Patel, Sam Schonwald
    
    properties (Access = private)
        User_In='' % Username from Login_screen
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, User_In)
            % Save variable into an app variable to use in other functions
            app.User_In = User_In;
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % Save username using the app variable created in startup
            user = {app.User_In};

            Disp_Database(user);
            closereq();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % Save username using the app variable created in startup
            user = {app.User_In};
            
            % Create variables with values equal to each input field
            % These will be used put data into SQL database
            % Also used to check if statements
            date = {char(app.Date.Value)};
            height = app.Height.Value;
            weight = app.Weight.Value;
            sputum_color = {app.SputumColor.Value};
            stool_consist = {app.StoolConsist.Value};
            stool_color = {app.StoolColor.Value};
            cough_freq = app.CoughFrequency.Value;
            effort_breath = app.EffortofBreathing.Value;
            level_activity = app.LevelofActivity.Value;
            amount_sputum = app.AmountofSputum.Value;
            stomach_pain = app.StomachPain.Value;
            appetite_level = app.AppetiteLevel.Value;

            % if statement used to check if any number input is invalid
            if height <= 0 || weight <=0 || cough_freq < 0 || ...
                    effort_breath < 0 || level_activity < 0 || ...
                    amount_sputum < 0 || stomach_pain < 0 || ...
                    appetite_level < 0 || cough_freq < 0 || ...
                    effort_breath > 10 || level_activity > 10 || ...
                    amount_sputum > 10 || stomach_pain > 10 || ...
                    appetite_level > 10

                app.ErrorNum.Visible = true;
                app.ErrorText.Visible = false;
                app.ErrorDate.Visible = false;

            elseif date > datetime('now')
                app.ErrorNum.Visible = false;
                app.ErrorText.Visible = false;
                app.ErrorDate.Visible = true;

            else

                % second if statement checks if any text value is empty
                if isempty(app.SputumColor.Value) ...
                        || isempty(app.StoolConsist.Value) || isempty(app.StoolColor.Value)
                    
                    app.ErrorText.Visible = true;
                    app.ErrorNum.Visible = false;
                    app.ErrorDate.Visible = false;

                else  
                    
                    % ImportData.m will input data into an SQL database
                    ImportData(user, date, height, weight, sputum_color, ...
                        stool_consist, stool_color, cough_freq, ...
                        effort_breath, level_activity, amount_sputum, ...
                        stomach_pain, appetite_level);

                    % Close out the survey and open the database
                    app.ErrorNum.Visible = false;                    
                    app.ErrorText.Visible = false;
                    app.ErrorDate.Visible = false;
                    Disp_Database(user);
                    closereq();
                    
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Cystic Fibrosis Symptoms Survey';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftPanel.Title = 'Fill in Appropriate Values';
            app.HeightinEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightinEditFieldLabel.Text = 'Height (in)';
            app.Height = uieditfield(app.LeftPanel, 'numeric');
            app.WeightlbEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightlbEditFieldLabel.Text = 'Weight (lb)';
            app.Weight = uieditfield(app.LeftPanel, 'numeric');
            app.SputumColorEditFieldLabel = uilabel(app.LeftPanel);
            app.SputumColorEditFieldLabel.Text = 'Sputum Color';
            app.SputumColor = uieditfield(app.LeftPanel, 'text');
            app.StoolConsistencyLabel = uilabel(app.LeftPanel);
            app.StoolConsistencyLabel.Text = 'Stool Consistency';
            app.StoolConsist = uieditfield(app.LeftPanel, 'text');
            app.StoolColorEditFieldLabel = uilabel(app.LeftPanel);
            app.StoolColorEditFieldLabel.Text = 'Stool Color';
            app.StoolColor = uieditfield(app.LeftPanel, 'text');
            app.DateDatePickerLabel = uilabel(app.LeftPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.Date = uidatepicker(app.LeftPanel);
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.Title = 'Fill with Numbers (0 - Lowest/None; 10 - Highest)';
            app.CoughFrequencyEditFieldLabel = uilabel(app.RightPanel);
            app.CoughFrequencyEditFieldLabel.Text = 'Cough Frequency';
            app.CoughFrequency = uieditfield(app.RightPanel, 'numeric');
            app.EffortofBreathingEditFieldLabel = uilabel(app.RightPanel);
            app.EffortofBreathingEditFieldLabel.Text = 'Effort of Breathing';
            app.EffortofBreathing = uieditfield(app.RightPanel, 'numeric');
            app.LevelofActivityEditFieldLabel = uilabel(app.RightPanel);
            app.LevelofActivityEditFieldLabel.Text = 'Level of Activity';
            app.LevelofActivity = uieditfield(app.RightPanel, 'numeric');
            app.AmountofSputumEditFieldLabel = uilabel(app.RightPanel);
            app.AmountofSputumEditFieldLabel.Text = 'Amount of Sputum';
            app.AmountofSputum = uieditfield(app.RightPanel, 'numeric');
            app.StomachPainEditFieldLabel = uilabel(app.RightPanel);
            app.StomachPainEditFieldLabel.Text = 'Stomach Pain';
            app.StomachPain = uieditfield(app.RightPanel, 'numeric');
            app.AppetiteLevelEditFieldLabel = uilabel(app.RightPanel);
            app.AppetiteLevelEditFieldLabel.Text = 'Appetite Level';
            app.AppetiteLevel = uieditfield(app.RightPanel, 'numeric');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit Survey';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'See Previous Symptoms';
            app.ErrorText = uilabel(app.UIFigure);
            app.ErrorText.Visible = 'off';
            app.ErrorText.Text = 'Error: Text Value Missing';
            app.ErrorNum = uilabel(app.UIFigure);
            app.ErrorNum.Visible = 'off';
            app.ErrorNum.Text = 'Error: Invalid Numeric Value';
            app.ErrorDate = uilabel(app.UIFigure);
            app.ErrorDate.Visible = 'off';
            app.ErrorDate.Text = 'Error: Invalid Date Selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Symptom_Survey(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end