classdef User_Registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UserRegistrationLabel          matlab.ui.control.Label
        PleaseFillOutAllUserRegistrationFieldsPanel  matlab.ui.container.Panel
        ErrorDOB                       matlab.ui.control.Label
        DateofBirth                    matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel     matlab.ui.control.Label
        Gender                         matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        ErrorEmpty                     matlab.ui.control.Label
        ErrorPasswords                 matlab.ui.control.Label
        ErrorUsername                  matlab.ui.control.Label
        LastName                       matlab.ui.control.EditField
        LastNameEditFieldLabel         matlab.ui.control.Label
        FirstName                      matlab.ui.control.EditField
        FirstNameEditFieldLabel        matlab.ui.control.Label
        ConfirmPassword                matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel  matlab.ui.control.Label
        Password                       matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        Username                       matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        SubmitButton                   matlab.ui.control.Button
        Ethnicity                      matlab.ui.control.DropDown
        EthnicityLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
% Corine Lontoc, Soham Patel, & Sam Schonwald
% Group 22

            % Create variables with values equal to each input field
            % These will be used put data into SQL database
            % Also used to check if statements
            username = {app.Username.Value};
            password = {app.Password.Value};
            confirm_password = {app.ConfirmPassword.Value};
            first_name = {app.FirstName.Value};
            last_name = {app.LastName.Value};
            ethnicity = {app.Ethnicity.Value};
            gender = {app.Gender.Value};
            date_of_birth = {char(app.DateofBirth.Value)};
        
            %encrypt the password to make sure it is not able t be accessed
            %by anyone from the database
           encrypted = convertCharsToStrings(encrypt_pass(app.Password.Value));
            
            % compare the password and confirm password fields to ensure
            % that they match, if not display error message
           if strcmp(password,confirm_password) == false
               app.ErrorPasswords.Visible = 'on';
               app.ErrorEmpty.Visible = 'off';  
               app.ErrorUsername.Visible = 'off';
               app.ErrorDOB.Visible = 'off';
           % ensure that there is a valid date of birth, if nt display
           % error message
           elseif date_of_birth > datetime('now')     
               app.ErrorDOB.Visible = 'on';
               app.ErrorEmpty.Visible = 'off';  
               app.ErrorUsername.Visible = 'off';
               app.ErrorPasswords.Visible = 'off';
           % display error message if usermatch determines that theres is
           % already an existing user with the inputted username
           elseif usermatch(username) == 1
               app.ErrorUsername.Visible = 'on';
               app.ErrorEmpty.Visible = 'off'; 
               app.ErrorPasswords.Visible = 'off';
               app.ErrorDOB.Visible = 'off';
           % displays an error message if any of the fields are empty
           elseif isempty(app.Username.Value) || isempty(app.Password.Value) ...
                        || isempty(app.ConfirmPassword.Value) || isempty(app.FirstName.Value)...
                        ||isempty(app.LastName.Value) ||isempty(app.Ethnicity.Value)...
                        ||isempty(app.Gender.Value) ||isempty(app.DateofBirth.Value)
                    app.ErrorEmpty.Visible = 'on';  
                    app.ErrorPasswords.Visible = 'off';
                    app.ErrorUsername.Visible = 'off';
                    app.ErrorDOB.Visible = 'off';
           else
                %runs registration function with ecrypted data
             RegisterEncrypted(username, encrypted, first_name, ...
                 last_name, ethnicity, gender, date_of_birth);
                %displays login screen
            Login_Screen;
            %closes current screen
            closereq();
             
           end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseFillOutAllUserRegistrationFieldsPanel = uipanel(app.UIFigure);
            app.PleaseFillOutAllUserRegistrationFieldsPanel.Title = 'Please Fill Out All User Registration Fields';
            app.EthnicityLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.EthnicityLabel.Text = 'Ethnicity:';
            app.Ethnicity = uidropdown(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.Ethnicity.Items = {'White', 'Black or African American', 'American Indian or Alaska Native', 'Asian', 'Native Hawaiian or Other Pacific Islander', 'Other'};
            app.Ethnicity.Value = 'White';
            app.SubmitButton = uibutton(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.UsernameEditFieldLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.UsernameEditFieldLabel.Text = 'Username:';
            app.Username = uieditfield(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.Password = uieditfield(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'text');
            app.ConfirmPasswordEditFieldLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password:';
            app.ConfirmPassword = uieditfield(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'text');
            app.FirstNameEditFieldLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.FirstNameEditFieldLabel.Text = 'First Name:';
            app.FirstName = uieditfield(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'text');
            app.LastNameEditFieldLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.LastNameEditFieldLabel.Text = 'Last Name:';
            app.LastName = uieditfield(app.PleaseFillOutAllUserRegistrationFieldsPanel, 'text');
            app.ErrorUsername = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ErrorUsername.Visible = 'off';
            app.ErrorUsername.Text = 'Error: Username is already taken';
            app.ErrorPasswords = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ErrorPasswords.Visible = 'off';
            app.ErrorPasswords.Text = 'Error: Passwords do not match';
            app.ErrorEmpty = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ErrorEmpty.Visible = 'off';
            app.ErrorEmpty.Text = 'Error: Please fill out all information';
            app.DropDownLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.Gender = uidropdown(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.Gender.Items = {'Male', 'Female', 'Other'};
            app.Gender.Value = 'Male';
            app.DateofBirthDatePickerLabel = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.DateofBirthDatePickerLabel.Text = 'Date of  Birth';
            app.DateofBirth = uidatepicker(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ErrorDOB = uilabel(app.PleaseFillOutAllUserRegistrationFieldsPanel);
            app.ErrorDOB.Visible = 'off';
            app.ErrorDOB.Text = 'Error: Invalid date of birth';
            app.UserRegistrationLabel = uilabel(app.UIFigure);
            app.UserRegistrationLabel.Text = 'User Registration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = User_Registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end