classdef DataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BMIValue            matlab.ui.control.Label
        BMILabel            matlab.ui.control.Label
        CaloricIntakeGraph  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataEntry, wt, ht, FoodInfo)
            app.CallingApp = DataEntry;
%             app.hDataEntry = DataEntry;
%             app.BMILabel.Text = num2str((DataEntry.Weight.Value)/(DataEntry.Height.Value)^2);
app.BMIValue.Text = num2str((wt)/(ht)^2);
% plot(app.CaloricIntakeGraph, FoodInfo{:,1}, FoodInfo{:,2})
plot(app.CaloricIntakeGraph, 1:height(FoodInfo), FoodInfo(:,2))
%             plot(app.CaloricIntakeGraph, x, [FoodInfo(1,2) FoodInfo(2,2) FoodInfo(3,2) FoodInfo(4,2) FoodInfo(5,2) FoodInfo(6,2) FoodInfo(7,2)])
        end

        % Button down function: CaloricIntakeGraph
        function CaloricIntakeGraphButtonDown(app, event)
            
        end

        % Callback function: not associated with a component
        function ReturntoDataEntryGUIButtonPushed(app, event)
%             app.DataEntry.Visible = 'on';
%                         app.Callingapp = DataAnalysis;
% commandwindow();
% uigetfile();
% appdesigner("DataEntry.mlapp")
 uistack(handles.DataEntry,'top');
 set(handles.DataEntry,'visible','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaloricIntakeGraph = uiaxes(app.UIFigure);
            title(app.CaloricIntakeGraph, 'Caloric Intake over Time')
            xlabel(app.CaloricIntakeGraph, 'Day')
            ylabel(app.CaloricIntakeGraph, 'Kilocalories')
            zlabel(app.CaloricIntakeGraph, 'Z')
            app.CaloricIntakeGraph.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.CaloricIntakeGraph.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.CaloricIntakeGraph.ButtonDownFcn = createCallbackFcn(app, @CaloricIntakeGraphButtonDown, true);
            app.BMILabel = uilabel(app.UIFigure);
            app.BMILabel.Text = 'BMI:';
            app.BMIValue = uilabel(app.UIFigure);
            app.BMIValue.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end