classdef DataEntry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SubmitButton               matlab.ui.control.Button
        InputHeightandWeightPanel  matlab.ui.container.Panel
        Weight                     matlab.ui.control.NumericEditField
        WeightlbEditFieldLabel     matlab.ui.control.Label
        Height                     matlab.ui.control.NumericEditField
        HeightftEditFieldLabel     matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        InputInformationPanel      matlab.ui.container.Panel
        Autocomplete               matlab.ui.control.ListBox
        AddFoodButton              matlab.ui.control.Button
        mmddyyyyLabel              matlab.ui.control.Label
        DateLabel                  matlab.ui.control.Label
        Date                       matlab.ui.control.EditField
        PortiongLabel              matlab.ui.control.Label
        FoodNameLabel              matlab.ui.control.Label
        Portion                    matlab.ui.control.NumericEditField
        FoodName                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
%         DataAnalysis = [] ;  % Description
        DataAnalysisapp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Autocomplete.Visible = 'on' ;
            diet_database = [bmes.datadir()  '/diet_database.sqlite'];
            db_diet = bmes_db(diet_database, 'database');
            distinct_food = db_diet.query((['SELECT distinct(FB.MainFoodDescription)' ...
                'FROM food_and_beverage_db as FB,' ...
                'FNDDS_nutrient_values_db as NUTR ' ...
                'WHERE FB.MainFoodDescription = NUTR.MainFoodDescription']));
            tabular_descriptions = struct2table(distinct_food);
            foods = table2array(tabular_descriptions);
            app.Autocomplete.Items = foods ;
        end

        % Button pushed function: AddFoodButton
        function AddFoodButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data;{app.Date.Value,app.FoodName.Value,app.Portion.Value}] ; 
            app.Date.Value = '' ; 
            app.FoodName.Value = '' ; 
            app.Portion.Value = 0;
            app.Autocomplete.Visible = 'off' ; 
        end

        % Value changed function: Autocomplete, FoodName
        function FoodNameValueChanged(app, event)
            app.Autocomplete.Visible = 'on' ;
            diet_database = [bmes.datadir()  '/diet_database.sqlite'];
            db_diet = bmes_db(diet_database, 'database');
            distinct_food = db_diet.query((['SELECT distinct(FB.MainFoodDescription)' ...
                'FROM food_and_beverage_db as FB,' ...
                'FNDDS_nutrient_values_db as NUTR ' ...
                'WHERE FB.MainFoodDescription = NUTR.MainFoodDescription']));
            tabular_descriptions = struct2table(distinct_food);
            foods = table2cell(tabular_descriptions);
            app.Autocomplete.Items = foods ; 
            itemlist = app.Autocomplete.Items ; 
            new = app.FoodName.Value ; 
            string_check = sum(strncmp(itemlist,new,length(new))) ;
                if string_check == 0
                    app.Autocomplete.Items = ["No Matches"]; 
                else
                    B = contains(itemlist,new) ; 
                    app.Autocomplete.Items = itemlist(B) ;
                end
               
        end

        % Callback function
        function AutocompleteValueChanged(app, event)
            value = app.Autocomplete.Value;
%             app.FoodName.Value = app.Autocomplete.Value ; 
        end

        % Value changed function: Weight
        function WeightValueChanged(app, event)
%             weight = app.Weight.Value;
            weight = app.Weight.Value ;
        end

        % Value changed function: Height
        function HeightValueChanged(app, event)
%             height = app.Height.Value;
            height = app.Height.Value ; 
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %             app.hDataAnalysis = DataAnalysis ;
            %             writecell(app.UITable.Data,'DataOutput.txt');
            diet_database = [bmes.datadir()  '/diet_database.sqlite'];
            db_diet = bmes_db(diet_database, 'database');
            FoodInfo = [] ;
            %             FoodInfo = zeros(7,2);
            TableData = app.UITable.Data ;
            foodnamestable = array2table(TableData);
            %             foodnames = foodnamestable.TableData2;
            %             foodnames = foodnamestable.Date;
            %                           for i = 1:length(foodnames)
            for i = 1:height(foodnamestable)
                Distinct_Food_Descriptions_WithNutrients = db_diet.query((['SELECT Distinct(FB.MainFoodDescription), NUTR.Energy_kcal, NUTR.Protein_grams, NUTR.Carbohydrate_grams, NUTR.Sugars_total_grams, NUTR.TotalFiber_grams, NUTR.TotalFat_grams, NUTR.SaturatedFattyAcids_total_grams, NUTR.MonoSaturatedFattyAcids_total_grams, NUTR.PolySaturatedFattyAcids_total_grams, NUTR.Cholesterol_milligrams, NUTR.Calcium_milligrams, NUTR.Phosphorus_milligrams, NUTR.Magnesium_milligrams, NUTR.Iron_milligrams, NUTR.Zinc_milligrams, NUTR.Copper_milligrams, NUTR.Selenium_micrograms, NUTR.Potassium_milligrams, NUTR.Sodium_milligrams, NUTR.Caffeine_milligrams, NUTR.Alcohol_grams ' ...
                    'FROM food_and_beverage_db as FB, ' ...
                    'FNDDS_nutrient_values_db as NUTR ' ...
                    append('WHERE FB.MainFoodDescription like ','"%',TableData{i,2},'%" ') ...
                    'AND FB.MainFoodDescription = NUTR.MainFoodDescription']));
                Table_Food_Descriptions = struct2cell(Distinct_Food_Descriptions_WithNutrients) ;
                %                 FoodInfo(i,1) = Table_Food_Descriptions.MainFoodDescription(i);
                %                 FoodInfo(i,2) = Table_Food_Descriptions.Energy_kcal(i);
                %                 FoodInfo(i,1) = Table_Food_Descriptions(1,i);
                FoodInfo(i,2) = Table_Food_Descriptions{2,i}*(TableData{i,3}/100);
%                 FoodInfo(i,3) = Table_Food_Descriptions{3,i}*(TableData{i,3}/100);
%                 FoodInfo(i,4) = Table_Food_Descriptions{4,i}*(TableData{i,3}/100);
%                 FoodInfo(i,5) = Table_Food_Descriptions{5,i}*(TableData{i,3}/100);
%                 FoodInfo(i,6) = Table_Food_Descriptions{6,i}*(TableData{i,3}/100);
%                 FoodInfo(i,7) = Table_Food_Descriptions{7,i}*(TableData{i,3}/100);
                %                 FoodInfo(i,8) = Table_Food_Descriptions.SaturatedFattyAcids_total_grams(i);
                %                 FoodInfo(i,9) = Table_Food_Descriptions.MonoSaturatedFattyAcids_total_grams(i);
                %                 FoodInfo(i,10) = Table_Food_Descriptions.PolySaturatedFattyAcids_total_grams(i);
                %                 FoodInfo(i,11) = Table_Food_Descriptions.Cholesterol_milligrams(i);
                %                 FoodInfo(i,12) = Table_Food_Descriptions.Calcium_milligrams(i);
                %                 FoodInfo(i,13) = Table_Food_Descriptions.Phosphorus_milligrams(i);
                %                 FoodInfo(i,14) = Table_Food_Descriptions.Magnesium_milligrams(i);
                %                 FoodInfo(i,15) = Table_Food_Descriptions.Iron_milligrams(i);
                %                 FoodInfo(i,16) = Table_Food_Descriptions.Zinc_milligrams(i);
                %                 FoodInfo(i,17) = Table_Food_Descriptions.Copper_milligrams(i);
                %                 FoodInfo(i,18) = Table_Food_Descriptions.Selenium_micrograms(i);
                %                 FoodInfo(i,19) = Table_Food_Descriptions.Potassium_milligrams(i);
                %                 FoodInfo(i,20) = Table_Food_Descriptions.Sodium_milligrams(i);
                %                 FoodInfo(i,21) = Table_Food_Descriptions.Caffeine_milligrams(i);
                %                 FoodInfo(i,22) = Table_Food_Descriptions.Alcohol_grams(i);
            end
            wt = app.Weight.Value;
            ht = app.Height.Value;
            app.DataAnalysisapp = DataAnalysis(app,wt,ht,FoodInfo);

            %             app.DataAnalysis = DataAnalysis(app) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputInformationPanel = uipanel(app.UIFigure);
            app.InputInformationPanel.Title = 'Input Information';
            app.FoodName = uieditfield(app.InputInformationPanel, 'text');
            app.FoodName.ValueChangedFcn = createCallbackFcn(app, @FoodNameValueChanged, true);
            app.Portion = uieditfield(app.InputInformationPanel, 'numeric');
            app.FoodNameLabel = uilabel(app.InputInformationPanel);
            app.FoodNameLabel.Text = 'Food Name';
            app.PortiongLabel = uilabel(app.InputInformationPanel);
            app.PortiongLabel.Text = 'Portion (g)';
            app.Date = uieditfield(app.InputInformationPanel, 'text');
            app.DateLabel = uilabel(app.InputInformationPanel);
            app.DateLabel.Text = 'Date';
            app.mmddyyyyLabel = uilabel(app.InputInformationPanel);
            app.mmddyyyyLabel.Text = '(mm/dd/yyyy)';
            app.AddFoodButton = uibutton(app.InputInformationPanel, 'push');
            app.AddFoodButton.ButtonPushedFcn = createCallbackFcn(app, @AddFoodButtonPushed, true);
            app.AddFoodButton.Text = 'Add Food';
            app.Autocomplete = uilistbox(app.InputInformationPanel);
            app.Autocomplete.Items = {};
            app.Autocomplete.ValueChangedFcn = createCallbackFcn(app, @FoodNameValueChanged, true);
            app.Autocomplete.Value = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Food Name'; 'Portion'};
            app.UITable.RowName = {};
            app.InputHeightandWeightPanel = uipanel(app.UIFigure);
            app.InputHeightandWeightPanel.Title = 'Input Height and Weight';
            app.HeightftEditFieldLabel = uilabel(app.InputHeightandWeightPanel);
            app.HeightftEditFieldLabel.Text = 'Height (ft):';
            app.Height = uieditfield(app.InputHeightandWeightPanel, 'numeric');
            app.Height.ValueChangedFcn = createCallbackFcn(app, @HeightValueChanged, true);
            app.WeightlbEditFieldLabel = uilabel(app.InputHeightandWeightPanel);
            app.WeightlbEditFieldLabel.Text = 'Weight (lb):';
            app.Weight = uieditfield(app.InputHeightandWeightPanel, 'numeric');
            app.Weight.ValueChangedFcn = createCallbackFcn(app, @WeightValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataEntry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end